classdef OPERA_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OPERA                        matlab.ui.Figure
        BrowseButton                 matlab.ui.control.Button
        InputLabel                   matlab.ui.control.Label
        InputTextArea                matlab.ui.control.TextArea
        OutputTextAreaLabel          matlab.ui.control.Label
        OutputTextArea               matlab.ui.control.TextArea
        CalculateButton              matlab.ui.control.Button
        ModelsPanel                  matlab.ui.container.Panel
        LogPCheckBox                 matlab.ui.control.CheckBox
        MPCheckBox                   matlab.ui.control.CheckBox
        PhyschempropertiesCheckBox   matlab.ui.control.CheckBox
        BPCheckBox                   matlab.ui.control.CheckBox
        VPCheckBox                   matlab.ui.control.CheckBox
        WSCheckBox                   matlab.ui.control.CheckBox
        HLCheckBox                   matlab.ui.control.CheckBox
        KOACheckBox                  matlab.ui.control.CheckBox
        RTCheckBox                   matlab.ui.control.CheckBox
        pKaCheckBox                  matlab.ui.control.CheckBox
        LogDCheckBox                 matlab.ui.control.CheckBox
        EnvironmentalfateCheckBox    matlab.ui.control.CheckBox
        ToxicityendpointsCheckBox    matlab.ui.control.CheckBox
        LogBCFCheckBox               matlab.ui.control.CheckBox
        AOHCheckBox                  matlab.ui.control.CheckBox
        BiodegCheckBox               matlab.ui.control.CheckBox
        RBiodegCheckBox              matlab.ui.control.CheckBox
        KMCheckBox                   matlab.ui.control.CheckBox
        KOCCheckBox                  matlab.ui.control.CheckBox
        ERCERAPPCheckBox             matlab.ui.control.CheckBox
        ARCoMPARACheckBox            matlab.ui.control.CheckBox
        AcuteToxCATMoSCheckBox       matlab.ui.control.CheckBox
        ADMEpropertiesCheckBox       matlab.ui.control.CheckBox
        FUBCheckBox                  matlab.ui.control.CheckBox
        ClintCheckBox                matlab.ui.control.CheckBox
        ResultssummaryTextAreaLabel  matlab.ui.control.Label
        ResultssummaryTextArea       matlab.ui.control.TextArea
        OutputoptionsPanel           matlab.ui.container.Panel
        SeparatefilesCheckBox        matlab.ui.control.CheckBox
        NearestneighborsCheckBox     matlab.ui.control.CheckBox
        KeepfulldescriptorsfilesCheckBox  matlab.ui.control.CheckBox
        IncludedescriptorvaluesCheckBox  matlab.ui.control.CheckBox
        ExperimentalvaluesCheckBox   matlab.ui.control.CheckBox
        Button_2                     matlab.ui.control.Button
        InfoModels                   matlab.ui.control.Button
        InfoOutput                   matlab.ui.control.Button
        BrowseButton_2               matlab.ui.control.Button
        InfoResutls                  matlab.ui.control.Button
        StandardizeButton            matlab.ui.control.StateButton
        InfoModels_2                 matlab.ui.control.Button
        InfoModels_3                 matlab.ui.control.Button
    end

  properties (Access = public)
        Outpath % Description
        Version='2.3';
        SubVersion='2.3-beta2';
        Date='(June 2019)';
  end

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %GETUSERDIR   return the user home directory.
            if ispc
                userDir = winqueryreg('HKEY_CURRENT_USER',...
                    ['Software\Microsoft\Windows\CurrentVersion\' ...
                    'Explorer\Shell Folders'],'Personal');
            else
                userDir = char(java.lang.System.getProperty('user.home'));
            end

            [filename, pathname] = uigetfile({'*.smi;*.sdf;*.mol;*.txt','Structure Files (*.smi,*.sdf,*.mol,*.txt)'}, 'Input File',userDir);
            if isequal(filename,0)||isequal(pathname,0)
                msgbox('No selected file', 'Warning','warn');
                return;
            end
            StructureFile= strcat(pathname, filename);
            FileOut= strcat(pathname, ['OPERA',app.Version,'_Pred.csv']);
            app.InputTextArea.Value=StructureFile;
            app.OutputTextArea.Value=FileOut;
            app.Outpath=pathname;
            StructureFile=char(StructureFile);
            if strcmpi(StructureFile(length(StructureFile)-3:end),'.smi')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"') ,' | FIND /C ":"']);%Win
                else
                    [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                end
                app.ResultssummaryTextArea.Value=['Structures in SMILES file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.txt')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"'),' | FIND /C ":"']);%win
                else
                    [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                end
                app.ResultssummaryTextArea.Value=['Found IDs in input text file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.sdf')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*$$$$" ', strcat('"',char(StructureFile),'"') ,' | FIND /C ":"']);%Win
                else
                    [~, numlines] = system(['grep -F "\$\$\$\$" ', strcat('"',char(StructureFile),'"'), ' | wc -l']); %linux
                end
                app.ResultssummaryTextArea.Value=['Structures in SDF file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.mol')
                app.ResultssummaryTextArea.Value='Using structure in MOL file';
            else
                msgbox('Check input file', 'Error','error');
                return;
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filename, pathname] = uiputfile({'*.txt;*.csv','Text Files (*.txt,*.csv)'}, 'Output File');
            FileOut= strcat(pathname, filename);
            app.OutputTextArea.Value=FileOut;
            app.Outpath=pathname;
        end

        % Callback function: CalculateButton, ResultssummaryTextArea
        function CalculateButtonPushed(app, event)
            
            if isdeployed
                warning('off','MATLAB:table:ModifiedAndSavedVarnames');
            else
                warning('on','MATLAB:table:ModifiedAndSavedVarnames');
            end
            
            salt=0;
%             help=0;
%             tox=0;
%             pc=0;
%             ef=0;
            fp=0;
            cdk=0;
            sep = app.SeparatefilesCheckBox.Value;
            exp= app.ExperimentalvaluesCheckBox.Value;
            neighbors = app.NearestneighborsCheckBox.Value;
            printtDesc= app.IncludedescriptorvaluesCheckBox.Value;
            clean=app.KeepfulldescriptorsfilesCheckBox.Value;
            
            StructureFile=char(app.InputTextArea.Value);
            %msgbox(StructureFile,'Error','error');
            %return;
            if isempty(StructureFile)
                msgbox('Select input file', 'Error','error');
                return;
            end
            FileOut=char(app.OutputTextArea.Value);
            if isempty(FileOut)
                msgbox('Enter output file', 'Error','error');
                return;
            end
            
            Mbox(1) = app.LogPCheckBox.Value;
            Mbox(2) = app.MPCheckBox.Value;
            Mbox(3) = app.BPCheckBox.Value;
            Mbox(4) = app.VPCheckBox.Value;
            Mbox(5) = app.WSCheckBox.Value;
            Mbox(6) = app.HLCheckBox.Value;
            Mbox(7) = app.KOACheckBox.Value;
            Mbox(8) = app.RTCheckBox.Value;
            Mbox(9) = app.pKaCheckBox.Value;
            Mbox(10)= app.LogDCheckBox.Value;
            Mbox(11)= app.LogBCFCheckBox.Value;
            Mbox(12)= app.AOHCheckBox.Value;
            Mbox(13)= app.BiodegCheckBox.Value;
            Mbox(14)= app.RBiodegCheckBox.Value;
            Mbox(15)= app.KMCheckBox.Value;
            Mbox(16)= app.KOCCheckBox.Value;
            Mbox(17)= app.FUBCheckBox.Value;
            Mbox(18)= app.ClintCheckBox.Value;
            Mbox(19)= app.ERCERAPPCheckBox.Value;
            Mbox(20)= app.ARCoMPARACheckBox.Value;
            Mbox(21)= app.AcuteToxCATMoSCheckBox.Value;
            Mbox(22)= 1;
            
            if length(find(Mbox))==1
                msgbox('No models selected', 'Error','error');
                return;
                %app.ResultssummaryTextArea.Value=num2str(Mbox);
            else
                steps=length(find(Mbox))+1;
            end
            
            %progress
            step=0;
            d = uiprogressdlg(app.OPERA,'Title','Please Wait','Message','Initializing...','Cancelable','on');
            timerAll=tic;
            
            Models= {'LogP','MP','BP','VP','WS','HL','KOA','RT','pKa', 'LogD','LogBCF', 'AOH', 'BioDeg', 'RBioDeg','KM',...
                'KOC','FuB', 'Clint', 'CERAPP', 'CoMPARA', 'CATMoS','StrP'};
            prop=Models(Mbox);
            
            %GETUSERDIR   return the user home directory.
            if ispc
                userDir = winqueryreg('HKEY_CURRENT_USER',...
                    ['Software\Microsoft\Windows\CurrentVersion\' ...
                    'Explorer\Shell Folders'],'Personal');
                installdir=fullfile('C:','Program Files','OPERA_UI','application');
            else
                userDir = char(java.lang.System.getProperty('user.home'));
                installdir=fullfile('/','usr','local','bin','OPERA_UI','application');
            end
            if ~isdeployed
                installdir=pwd;
            end
            
            if ~exist(fullfile(installdir,'padel-full-1.00.jar'),'file')
                
                if isdeployed
                    currentDir=ctfroot;
                    if exist(fullfile(currentDir,'OPERA_installdir.txt'),'file')
                        fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'r');
                        installdir=strip(fread(fid,'*char')');
                        fclose(fid);
                        %currentDir = pwd;
                        if ~exist(fullfile(installdir,'padel-full-1.00.jar'),'file')
                            %app.ResultssummaryTextArea.Value=currentDir;
                            uiwait(msgbox([{'Default install folder was changed during installation.'};...
                                {'You will be prompted to select the custom folder.'}], 'Warning','warning'));
                            installdir = uigetdir(userDir, 'Select installation folder');
                            if ~exist(fullfile(installdir,'padel-full-1.00.jar'),'file')
                                msgbox(sprintf('Wrong Installation folder.\nSelect "application" under "OPERA_UI".'), 'Error','error');
                                return;
                            end
                            fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'w');
                            fprintf(fid,'%s',installdir);
                            fclose(fid);
                        end
                    else
                        uiwait(msgbox([{'Default install folder was changed during installation.'};...
                                {'You will be prompted to select the custom folder.'}], 'Warning','warning'));
                        installdir = uigetdir(userDir, 'Select installation folder');
                        if ~exist(fullfile(installdir,'padel-full-1.00.jar'),'file')
                                msgbox(sprintf('Wrong Installation folder.\nSelect "application" under "OPERA_UI".'), 'Error','error');
                                return;
                        end
                        fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'w');
                        fprintf(fid,'%s',installdir);
                        fclose(fid);
                        
                    end
                    
                else
                    currentDir = pwd;
                    if exist(fullfile(currentDir,'OPERA_installdir.txt'),'file')
                        fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'r');
                        installdir=strip(fread(fid,'*char')');
                        fclose(fid);
                        %currentDir = pwd;
                        if ~exist(fullfile(installdir,'padel-full-1.00.jar'),'file')
                            %app.ResultssummaryTextArea.Value=currentDir;
                            uiwait(msgbox([{'Default install folder was changed during installation.'};...
                                {'You will be prompted to select the custom folder.'}], 'Warning','warning'));
                            installdir = uigetdir(userDir, 'Select installation folder');
                            if ~exist(fullfile(installdir,'padel-full-1.00.jar'),'file')
                                msgbox(sprintf('Wrong Installation folder.\nSelect "application" under "OPERA_UI".'), 'Error','error');
                                return;
                            end
                            fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'w');
                            fprintf(fid,'%s',installdir);
                            fclose(fid);
                        end
                    else
                        uiwait(msgbox([{'Default install folder was changed during installation.'};...
                                {'You will be prompted to select the custom folder.'}], 'Warning','warning'));
                        installdir = uigetdir(userDir, 'Select installation folder');
                        if ~exist(fullfile(installdir,'padel-full-1.00.jar'),'file')
                                msgbox(sprintf('Wrong Installation folder.\nSelect "application" under "OPERA_UI".'), 'Error','error');
                                return;
                        end
                        fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'w');
                        fprintf(fid,'%s',installdir);
                        fclose(fid);
                        
                    end
                end
            end
            
            if strcmpi(StructureFile(length(StructureFile)-3:end),'.smi')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"'),' | FIND /C ":"']);%win
                else
                    [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                end
                app.ResultssummaryTextArea.Value=['Loaded structures from SMILES file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.txt')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"'),' | FIND /C ":"']);%win
                else
                    [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                end
                app.ResultssummaryTextArea.Value=['Found IDs in input text file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.sdf')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*$$$$" ', strcat('"',char(StructureFile),'"') ,' | FIND /C ":"']);%win
                else
                    [~, numlines] = system(['grep -F "\$\$\$\$" ', strcat('"',char(StructureFile),'"'), ' | wc -l']); %linux
                end
                app.ResultssummaryTextArea.Value=['Loaded structures from SDF file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.mol')
                app.ResultssummaryTextArea.Value='Loaded structure from MOL file';
                numlines='1';
            else
                msgbox('Check input file', 'Error','error');
                return;
            end
            
            if str2num(numlines)==0
                msgbox('No structures found! Check the input file.','Error','error');
                return;
            end
            
            train=load ('OPERA_models.mat', '-mat');
            %Xlabels=train.labels;
            %XlabelsFP=train.labels_fp;
            
            %find structures
            if strcmpi(StructureFile(length(StructureFile)-3:end),'.txt')
                steps=steps+1;
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Getting structures based on provided IDs.';
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end  
                fid = fopen(StructureFile);
                indic = 1;
                while 1
                    tline = fgetl(fid);
                    if ~ischar(tline)
                        break
                    end
                    strings{indic}=tline;
                    indic = indic + 1;
                end
                fclose(fid);
                StructureFile=strcat(StructureFile(1:length(StructureFile)-3),'smi');
                fileID = fopen(StructureFile, 'w');
                f=0;
                La=zeros(length(strings));
                Lb=zeros(length(strings));
                for i=1:length(strings)
                    
                    if regexp(strings{i},'[0-9]+-[0-9]+-[0-9]')
                        [La(i),Lb(i)] = ismember(strings{i},train.DSSToxQSARr{:,2});
                    elseif regexp(strings{i},'DTXSID[0-9]+')
                        [La(i),Lb(i)] = ismember(strings{i},train.DSSToxQSARr{:,3});
                    elseif regexp(strings{i},'DTXCID[0-9]+')
                        [La(i),Lb(i)] = ismember(strings{i},train.DSSToxQSARr{:,4});
                    elseif regexp(strings{i},'[A-Z]+-[A-Z]+-[A-Z]')
                         [La(i),Lb(i)] = ismember(strings{i},train.DSSToxQSARr{:,5});
                    end
                    if La(i)
                        f=f+1;
                        fprintf(fileID,'%s\t%s\n',train.DSSToxQSARr{Lb(i),1},strings{i});
                        if ismember('mp',lower(prop))
                            salt=1;
                            %SaltInfo(f,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            SaltIndex(f,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                        end
                    end
                end
                fclose(fileID);
                numlines=num2str(f);
                app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Found structures based on provided IDs: ',numlines]; 
                if f==0
                    msgbox('No structures found! Check the input file.','Error','error');
                    return;
                end
%                 if ismember('mp',lower(prop)) && salt==0
%                     %steps=steps+1;
%                     %step=step+1;
%                     %d.Value=step/steps;
%                     %d.Message = 'Generating and loading salt info for MP.';
%                     if d.CancelRequested
%                         msgbox('Canceled by user', 'Error','error');
%                         return;
%                     end
%                     salt=1;
%                     FileSalt=strcat(StructureFile(1:length(StructureFile)-4),'_SaltInfo','.csv');
%                     SaltFile.Name=strings(find(La))';
%                     SaltFile.SaltInfo=SaltInfo;
%                     SaltFile=struct2table(SaltFile);
%                     writetable(SaltFile,FileSalt,'Delimiter',',');
%                     clear('SaltInfo','SaltFile');
%                     %read SaltInfo
%                     SaltIndex=readtable(FileSalt,'delimiter',',');
%                     if size(SaltIndex,1)==f %size(Xin,1)
%                         if cellfun(@ischar,table2cell(SaltIndex(1,end)))
%                             Temp(:,:)=str2double(table2cell(SaltIndex(:,end)));
%                         else
%                             
%                             Temp(:,:)=SaltIndex{:,end};
%                         end
%                         
%                         clear('SaltIndex');
%                         SaltIndex=Temp;
%                         clear('Temp');
%                         app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'The number of molecules with salt information: ', num2str(length(find(SaltIndex)))];
%                     else
%                         msgbox('The number of saltIDs and structures must match.', 'Error','error');
%                         return;
%                     end
%                     
%                     %res.SaltID=SaltIndex;
%                     %End SaltInfo
%                 end
                
                
            end
            
            %%
            
            
             %========== output files ==========
            ext=FileOut(length(FileOut)-3:end);
            
            if sep==1
                outputname=cell(size(prop));
                %FileOut=strrep(FileOut,ext,'');
                FileOut=FileOut(1:length(FileOut)-4);
                %app.ResultssummaryTextArea.Value=FileOut;
                for i=1:length(prop)
                    outputname{i}=char(strcat(FileOut, '_', prop(i), ext));
                    %app.ResultssummaryTextArea.Value=outputname{i};
                    [output(i),errmsg]=fopen(outputname{i},'w');
                    if ~isempty(errmsg)
                        msgbox(errmsg, 'Error','error');
                        return;
                    end
                end
                FileOut=outputname;
            else
                steps=steps+1;
                [output,errmsg]=fopen(FileOut,'w');
                if ~isempty(errmsg)
                    msgbox(errmsg, 'Error','error');
                    return;
                end
            end
            
            [L,~] =ismember({'pka','logd'},lower(prop));
            if find(L)
                fp=1;
                steps=steps+2;
            end
            [L,~] =ismember({'fub','clint','cerapp','compara','catmos'},lower(prop));
            if find(L)
                cdk=1;
                steps=steps+2;
            end

            %========== Molecular Descriptors ==========
            
            %Calculating PaDEL descriptors...
            step=step+1;
            d.Value=step/steps;
            d.Message = 'Calculating descriptors: PaDEL 2D';
            if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
            end            
            %InputDescCDK=strcat(app.Outpath,'PadelDesc.csv');
            InputDesc=strcat(StructureFile(1:length(StructureFile)-4),'_PadelDesc.csv');
            %app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; InputDesc];
            %app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;fullfile(installdir,'padel-full-1.00.jar')];
            %InputDesc=app.InputTextArea.Value{:};
            timerPadel=tic;
           [statusDesc,cmdoutDesc] =system (['java -Djava.awt.headless=true -jar ' strcat('"',fullfile(installdir,'padel-full-1.00.jar'),'"')...
               ' -2d -removesalt -standardizenitro -detectaromaticity -retainorder -maxruntime 60000 -dir ' strcat('"',char(StructureFile),'"') ' -file ' strcat('"',InputDesc,'"')...
               ' > ' strcat('"',app.Outpath,'PaDELlogfile.log','"')]);
           timerPadel=toc(timerPadel);
           
           if statusDesc~=0 && ~isempty(cmdoutDesc)
               msgbox(cmdoutDesc, 'Error','error');
               return;
           end
           
                      
            %Loading of PaDEL descriptors file...
            step=step+1;
            d.Value=step/steps;
            d.Message = 'Checking PaDEL descriptors';
            if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
            end

            try
                Xin=readtable(InputDesc,'delimiter',',');
            catch ME
                if strcmp(ME.identifier,'MATLAB:readtable:OpenFailed')
                    msgbox('Unable to open descriptors file', 'Error','error');
                    return;
                else
                    msgbox(sprintf(ME.message), 'Error','error');
                    return;
                end
            end
            Xlabels=Xin.Properties.VariableNames;            
            if strcmpi(Xlabels{1},'Name') || strcmpi(Xlabels{1},'MoleculeID') || strcmpi(Xlabels{1},'Molecule')
                Xin.Properties.VariableNames{1}='Name';
                Xlabels=Xlabels(2:end);
                Names=Xin.Name;
                if isnumeric(Names) && strcmpi(ext,'.txt')
                    
                    for i=1:size(Xin,1)
                        MoleculeNames{i,1}=strcat('AUTOGEN_',num2str(Names(i)));
                    end
                else
                    MoleculeNames=Names;
                end
                %Xin=Xin{:,2:end};
                Xin=Xin(:,2:end);
            else
                
                for i=1:size(Xin,1)
                    MoleculeNames{i,1}=strcat('AUTOGEN_',num2str(i));
                end
            end
            if size(Xin,1)==0 || size(Xin,2)==0
                msgbox('Empty descriptors file! Check input structure(s).', 'Error','error');
                return;
            elseif size(Xin,1) < str2double(numlines)
                app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'Calculated molecules: ',num2str(size(Xin,1))];
                msgbox('PaDEL descriptors failed. Check input structure(s)!', 'Error','error');
                return;
            elseif salt==1 && size(SaltIndex,1)~=size(Xin,1)
                msgbox('The number of saltIDs and structures must match.', 'Error','error');
                return; 
            end
            i=1;
            Temp=zeros(size(Xin));
            while i<=length(Xlabels)
                if cellfun(@ischar,table2cell(Xin(1,i)))
                    Temp(:,i)=str2double(table2cell(Xin(:,i)));
                else
                    
                    Temp(:,i)=Xin{:,i};
                end
                i=i+1;
            end
            app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Calculated PaDEL descriptors: ' num2str(length(Xlabels)) ' (' num2str(round(timerPadel)) ' sec)'];
  
            clear('Xin');
            Xin=Temp;
            clear('Temp');
            
            %Calculating CDK Descriptors...
            if cdk==1
               step=step+1;
               d.Value=step/steps;
               d.Message = 'Calculating descriptors: CDK 2D';
               if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
               end
               %Bond_HA_r=Xin(:,466)./Xin(:,9);
               Amb_str=intersect(find((Xin(:,466)./Xin(:,9))>1.3),find(Xin(:,9)>50));
               if ~isempty(Amb_str)||~isempty(find(Xin(:,9)>150, 1))
                   Amb_str=num2str(Amb_str);
                   Amb_str=strjoin(num2cell(Amb_str(1:length(Amb_str))),',  ');
                   msgbox(['CDK descriptors cannot be calculated for structure(s) number: ', Amb_str,'.'],'Error','error');
                   return;
               end
               %InputDescCDK=strcat(app.Outpath,'CDKDesc.csv');
               InputDescCDK=strcat(StructureFile(1:length(StructureFile)-4),'_CDKDesc.csv');
               timerPadelCDK=tic;
               [statusDesc,cmdoutDesc] =system (['java -jar ' strcat('"',fullfile(installdir,'CDKDescUI-2.0.jar'),'"') ' -b -a -t all -o ' strcat('"',InputDescCDK,'"')...
                   ' ' strcat('"',char(StructureFile),'"') ' > ' strcat('"',app.Outpath,'CDKlogfile.log','"') ' 2> ' strcat('"',app.Outpath,'CDKerr.log','"')]);
               timerCDK=toc(timerPadelCDK);
               if statusDesc~=0 && ~isempty(cmdoutDesc)
                   msgbox(cmdoutDesc, 'Error','error');
                   return;
               end
            %end 
            %Loading of CDK descriptors file...
            %if cdk==1
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Checking CDK descriptors';
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                try
                    XinCDK=readtable(InputDescCDK,'delimiter','\t');
                catch ME
                    if strcmp(ME.identifier,'MATLAB:readtable:OpenFailed')
                        error('Cause: Unable to open descriptors file');
                    else
                        error(ME.message);
                        return;
                    end
                end
                if size(XinCDK,1)==0 || size(XinCDK,2)==0
                    error('Empty descriptors file!');        
                end
                XinCDK(:,1)=[];
                if size(XinCDK,1)~=size(Xin,1)
                    msgbox('Mismatch between PaDEL and CDK descriptors files', 'Error','error');
                    return;
                elseif strcmpi(XinCDK.Properties.VariableNames(end),'Zagreb')
                    XlabelsCDK=XinCDK.Properties.VariableNames;
                elseif strcmpi(XinCDK.Properties.VariableNames(end),'nAcid')
                    XinCDK=XinCDK(:,train.reorder_CDK);
                    XlabelsCDK=XinCDK.Properties.VariableNames;
                else
                    msgbox('Check CDK descriptors file', 'Error','error');
                    return;
                end
                i=1;
                Temp=zeros(size(XinCDK));
                while i<=length(XlabelsCDK)
                    if cellfun(@ischar,table2cell(XinCDK(1,i)))
                        Temp(:,i)=str2double(table2cell(XinCDK(:,i)));
                    else
                        Temp(:,i)=XinCDK{:,i};
                    end
                    i=i+1;
                end
                
                app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Calculated CDK descriptors: ' num2str(length(XlabelsCDK)) ' (' num2str(round(timerCDK)) ' sec)'];
                clear('XinCDK');
                XinCDK=Temp;
                clear('Temp');
            end
            %Generating PaDEL fingerprints...
            if fp==1
               step=step+1;
               d.Value=step/steps;
               d.Message = 'Calculating descriptors: PaDEL fingerprints';
               if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
               end
               %InputDescFP=strcat(app.Outpath,'PadelFP.csv');
               InputDescFP=strcat(StructureFile(1:length(StructureFile)-4),'_PadelFP.csv');
               timerPadelFP=tic;
               [statusDesc,cmdoutDesc] =system (['java -Djava.awt.headless=true -jar ' strcat('"',fullfile(installdir,'padel-full-1.00.jar'),'"')...
                   ' -fingerprints -descriptortypes ' strcat('"',fullfile(installdir,'desc_fp.xml'),'"') ' -removesalt -standardizenitro -detectaromaticity -retainorder -maxruntime 60000 -dir '...
                   strcat('"',char(StructureFile),'"') ' -file ' strcat('"',InputDescFP,'"') ' > ' strcat('"',app.Outpath,'PaDELlogfileFP.log','"')]);
               timerPadelFP=toc(timerPadelFP);
               if statusDesc~=0 && ~isempty(cmdoutDesc)
                   msgbox(cmdoutDesc, 'Error','error');
                   return;
               end
            %end
            %Loading of fingerprints file...
            %if fp==1
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Checking PaDEL fingerprints';
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                try
                    XinFP=readtable(InputDescFP,'delimiter',',');
                catch ME
                    if strcmp(ME.identifier,'MATLAB:readtable:OpenFailed')
                        error('Cause: Unable to open descriptors file');
                    else
                        error(ME.message);
                        return;
                    end
                end
                XlabelsFP=XinFP.Properties.VariableNames;
                if size(XinFP,1)==0 || size(XinFP,2)==0
                    error('Empty descriptors file!');        
                end
                XinFP=XinFP(:,2:end);
                if size(XinFP,1)~=size(Xin,1)
                    msgbox('Mismatch between descriptors and fingerprint files', 'Error','error');
                    return;
                else
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Generated PaDEL fingerprints: ' num2str(length(XlabelsFP)) ' (' num2str(round(timerPadelFP)) ' sec)'];
                end
            end


            if clean==0
                delete(InputDesc);
                %delete(strcat(app.Outpath,'PadelDesc.csv'));
                delete(strcat(app.Outpath,'PaDELlogfile.log'));
                if fp==1
                    delete(InputDescFP);
                    %delete(strcat(app.Outpath,'PadelFP.csv'));
                    delete(strcat(app.Outpath,'PaDELlogfileFP.log'));
                end
                if cdk==1
                    delete(InputDescCDK);
                    %delete(strcat(app.Outpath,'CDKDesc.csv'));
                    delete(strcat(app.Outpath,'CDKlogfile.log'));
                    delete(strcat(app.Outpath,'CDKerr.log'));
                end
            end
            
            %========== Running the models ==========
            
            %---------- General Structural properties ----------%
            [Lia,Locb] = ismember('strp',lower(prop));
            if Lia
                %step=step+1;
                %d.Value=step/steps;
                %d.Message = 'Calculating general structural properties';
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                Xtest=Xin(:,train.StrP.Desc_i);
                Desc={'MolWeight','nbAtoms','nbHeavyAtoms','nbC','nbO','nbN','nbAromAtom','nbRing','nbHeteroRing','Sp3Sp2HybRatio','nbRotBd','nbHBdAcc','ndHBdDon','nbLipinskiFailures','TopoPolSurfAir','MolarRefract','CombDipolPolariz'};
                Xtest=array2table(Xtest,'VariableNames',Desc);
                T=array2table(MoleculeNames,'VariableNames',{'MoleculeID'});
                T=[T Xtest];
                if sep==1
                    if strcmpi(ext,'.csv')
                        writetable(T,FileOut{Locb},'Delimiter',',');%,'QuoteStrings',true);
                        fclose(output(Locb));
                    elseif strcmpi(ext,'.txt')
                        fprintf(output(Locb(1)),'\n\n\t\t\t\t\t General Structural properties... \n\n			============================================================== \n\n');
                        for i=1:size(Xtest(:,1))
                            fprintf(output(Locb(1)),'\t Molecule %s:\n', MoleculeNames{i});
                            for j=1:length(Desc)
                                fprintf(output(Locb(1)),'%s= %.3f\t;\t', Desc{j},Xtest{i,j});
                            end
                            fprintf(output(Locb(1)),'\n');
                        end
                    end
                    
                elseif strcmpi(ext,'.txt')
                    fprintf(output,'\n\n\t\t\t\t\t General Structural properties... \n\n			============================================================== \n\n');
                    for i=1:size(Xtest(:,1))
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        for j=1:length(Desc)
                            fprintf(output,'%s= %.3f\t;\t', Desc{j},Xtest{i,j});
                        end
                        fprintf(output,'\n');
                    end
                end
                res=table2struct(T,'ToScalar',true);
                
                if sep==1
                    resf.StrP=table2struct(T,'ToScalar',true);
                    clear('res');
                end
                clear('T');
            end            
            
            DescMat=[];
            DescNames={};
            timerCalc=tic;
            %---------- PhysChem properties ----------
            %Predict LogP values
            [Lia,Locb] =ismember({'logp','logd'},lower(prop));
            if find(Lia)
                if Lia(1)
                    step=step+1;
                    d.Value=step/steps;
                end
                d.Message = 'Predicting LogP values';
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                Desc=train.LogP.Desc;
                if strcmpi(ext,'.txt') && sep==0 && Lia(1)
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogP values... \n\n			==============================================================n\n');
                end
                Xtest=Xin(:,train.LogP.Desc_i);
                pred = nnrpred(Xtest,train.LogP.model.set.train,train.LogP.model.set.y,train.LogP.model.set.K,train.LogP.model.set.dist_type,train.LogP.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogP_exp=NaN(size(Xtest,1),1);
                end
                res.LogP_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.LogP.model.set.train,Xtest,'auto');
                res.AD_LogP=abs(AD.inorout-1)';
                res.AD_LogP(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_LogP=zeros(size(Xtest,1),1);
                res.Conf_index_LogP=zeros(size(Xtest,1),1);
                LogP_CAS_neighbor=cell(size(Xtest,1),5);
                LogP_InChiKey_neighbor=cell(size(Xtest,1),5);
                LogP_DTXSID_neighbor=cell(size(Xtest,1),5);
                LogP_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogP_Exp_neighbor=zeros(size(Xtest,1),5);
                LogP_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.LogP.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.LogP.DTXSID);
                        end
                        if Li
                            if Lo>size(train.LogP.DTXSID,1)
                                Lo=mod(Lo,size(train.LogP.DTXSID,1));
                            end
                            res.LogP_exp(i)=train.LogP.model.set.y(Lo);
                        end
                    end
                    LogP_CAS_neighbor(i,:)=train.LogP.CAS(pred.neighbors(i,:));
                    LogP_InChiKey_neighbor(i,:)=train.LogP.InChiKey(pred.neighbors(i,:));
                    LogP_DTXSID_neighbor(i,:)=train.LogP.DTXSID(pred.neighbors(i,:));
                    LogP_DSSTOXMPID_neighbor(i,:)=train.LogP.DSSTOXMPID(pred.neighbors(i,:));
                    LogP_Exp_neighbor(i,:)=train.LogP.model.set.y(pred.neighbors(i,:));
                    LogP_pred_neighbor(i,:)=train.LogP.model.yc_weighted(pred.neighbors(i,:));
                    
                    res.AD_index_LogP(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');

                    res.Conf_index_LogP(i,1)=((1/(1+sqrt(((LogP_Exp_neighbor(i,:)-LogP_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_LogP(i,1))/2;
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.LogP_pred(i,1)=NaN;
                        res.AD_LogP(i)=0;
                        res.AD_index_LogP(i)=0;
                        res.Conf_index_LogP(i,1)=0;
                    end
                    
                    if neighbors==1
                        res.LogP_CAS_neighbor(i,:)=LogP_CAS_neighbor(i,:);
                        res.LogP_InChiKey_neighbor(i,:)=LogP_InChiKey_neighbor(i,:);
                        res.LogP_DTXSID_neighbor(i,:)=LogP_DTXSID_neighbor(i,:);
                        res.LogP_DSSTOXMPID_neighbor(i,:)=LogP_DSSTOXMPID_neighbor(i,:);
                        res.LogP_Exp_neighbor(i,:)=LogP_Exp_neighbor(i,:);
                        res.LogP_pred_neighbor(i,:)=LogP_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1 && Lia(1)
                        fprintf(output(Locb(1)),'\t Molecule %s:\n', res.MoleculeID{i});
                        if exp
                            fprintf(output(Locb(1)),'LogP experimental= %.3f\n', res.LogP_exp(i));
                        end
                        fprintf(output(Locb(1)),'LogP predicted= %.3f\n', res.LogP_pred(i));
                        if res.AD_LogP(i)==1
                            fprintf(output(Locb(1)),'AD: inside\n');
                        else
                            fprintf(output(Locb(1)),'AD: outside\n');
                        end
                        fprintf(output(Locb(1)),'AD_index= %.2f\n', res.AD_index_LogP(i));
                        fprintf(output(Locb(1)),'Conf_index= %.2f\n', res.Conf_index_LogP(i));
                        if neighbors==1
                            fprintf(output(Locb(1)),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.LogP.model.set.K,res.LogP_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(1)),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.LogP.model.set.K, res.LogP_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(1)),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.LogP.model.set.K, res.LogP_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0 && Lia(1)
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n',res.MoleculeID{i});
                        if exp
                            fprintf(output,'LogP experimental= %.3f\n', res.LogP_exp(i));
                        end
                        fprintf(output,'LogP predicted= %.3f\n', res.LogP_pred(i));
                        if res.AD_LogP(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_LogP(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_LogP(i));
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.LogP.model.set.K, res.LogP_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.LogP.model.set.K, res.LogP_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.LogP.model.set.K, res.LogP_pred_neighbor(i,1:5));
                        end
                    end
                end
                                
                if sep==1 && strcmpi(ext,'.csv') && Lia(1)
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(1)},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(1)));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv') && Lia(1)
                                        
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];                    
                    
                end
                
                if sep==1
                    resf.LogP=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
            end
            %Predict MP values
            [Lia,Locb] =ismember('mp',lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Predicting MP values (Deg. C)';
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                Desc=train.MP.Desc;
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting MP values... \n\n			============================================================== \n\n');
                end
                Xtest=Xin(:,train.MP.Desc_i);
                AD=classical_leverage(train.MP.model.set.train(:,1:end-1),Xtest,'auto');
                if salt ==0
                    SaltIndex=zeros(size(Xtest,1),1);
                    La=zeros(length(MoleculeNames));
                    Lb=zeros(length(MoleculeNames));
                    for i=1:length(MoleculeNames)
                        if ~contains(MoleculeNames(i),'AUTOGEN_')
                            if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,2});
                            elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,3});
                            elseif regexp(MoleculeNames{i},'DTXCID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,4});
                            elseif regexp(MoleculeNames{i},'[A-Z]+-[A-Z]+-[A-Z]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,5});
                            end
                            if La(i)
                                salt=1;
                                SaltIndex(i,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            end
                        end
                    end
                end
                if salt==1
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Salt info. retrieved for MP predictions'];
                end
                Xtest=[Xtest SaltIndex];
                Desc=[Desc,'SaltIndex'];
                pred = nnrpred(Xtest,train.MP.model.set.train,train.MP.model.set.y,train.MP.model.set.K,train.MP.model.set.dist_type,train.MP.model.set.param.pret_type);
                pred.D=diag(pred.D);
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.MP_exp=NaN(size(Xtest,1),1);
                end
                res.MP_pred(:,1)=pred.y_pred_weighted;
                res.AD_MP=abs(AD.inorout-1)';
                res.AD_MP(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_MP=zeros(size(Xtest,1),1);
                res.Conf_index_MP=zeros(size(Xtest,1),1);
                MP_CAS_neighbor=cell(size(Xtest,1),5);
                MP_InChiKey_neighbor=cell(size(Xtest,1),5);
                MP_DTXSID_neighbor=cell(size(Xtest,1),5);
                MP_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                MP_Exp_neighbor=zeros(size(Xtest,1),5);
                MP_pred_neighbor=zeros(size(Xtest,1),5);
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.MP.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.MP.DTXSID);
                        end
                        if Li
                            if Lo>size(train.MP.DTXSID,1)
                                Lo=mod(Lo,size(train.MP.DTXSID,1));
                            end
                            res.MP_exp(i)=train.MP.model.set.y(Lo);
                        end
                    end
                    MP_CAS_neighbor(i,:)=train.MP.CAS(pred.neighbors(i,:));
                    MP_InChiKey_neighbor(i,:)=train.MP.InChiKey(pred.neighbors(i,:));
                    MP_DTXSID_neighbor(i,:)=train.MP.DTXSID(pred.neighbors(i,:));
                    MP_DSSTOXMPID_neighbor(i,:)=train.MP.DSSTOXMPID(pred.neighbors(i,:));
                    if salt ==1
                        MP_Exp_neighbor(i,:)=train.MP.model.set.y(pred.neighbors(i,:));
                        MP_pred_neighbor(i,:)=train.MP.model.yc_weighted(pred.neighbors(i,:));
                    else
                        MP_Exp_neighbor(i,:)=train.MP.model.set.y(pred.neighbors(i,:));
                        MP_pred_neighbor(i,:)=train.MP.model.yc_weighted(pred.neighbors(i,:));
                    end
                    res.AD_index_MP(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_MP(i,1)=((1/(1+sqrt(((MP_Exp_neighbor(i,:)-MP_pred_neighbor(i,:)).^2)*pred.w(i,:)')/50))+res.AD_index_MP(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.MP_pred(i,1)=NaN;
                        res.AD_MP(i)=0;
                        res.AD_index_MP(i)=0;
                        res.Conf_index_MP(i,1)=0;
                    end
                    
                    if neighbors==1
                        res.MP_CAS_neighbor(i,:)=MP_CAS_neighbor(i,:);
                        res.MP_InChiKey_neighbor(i,:)=MP_InChiKey_neighbor(i,:);
                        res.MP_DTXSID_neighbor(i,:)=MP_DTXSID_neighbor(i,:);
                        res.MP_DSSTOXMPID_neighbor(i,:)=MP_DSSTOXMPID_neighbor(i,:);
                        res.MP_Exp_neighbor(i,:)=MP_Exp_neighbor(i,:);
                        res.MP_pred_neighbor(i,:)=MP_pred_neighbor(i,:);
                    end
                    if strcmpi(ext,'.txt') && sep==1
                        fprintf(output(Locb),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb),'MP experimental= %.3f\n', res.MP_exp(i));
                        end
                        fprintf(output(Locb),'MP predicted= %.3f\n', res.MP_pred(i));
                        if res.AD_MP(i)==1
                            fprintf(output(Locb),'AD: inside\n');
                        else
                            fprintf(output(Locb),'AD: outside\n');
                        end
                        fprintf(output(Locb),'AD_index= %.2f\n', res.AD_index_MP(i));
                        fprintf(output(Locb),'Conf_index= %.2f\n', res.Conf_index_MP(i));
                        if neighbors==1
                            fprintf(output(Locb),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.MP.model.set.K, res.MP_CAS_neighbor{i,1:5});
                            fprintf(output(Locb),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.MP.model.set.K, res.MP_Exp_neighbor(i,1:5));
                            fprintf(output(Locb),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.MP.model.set.K, res.MP_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'MP experimental= %.3f\n', res.MP_exp(i));
                        end
                        fprintf(output,'MP predicted= %.3f\n', res.MP_pred(i));
                        if res.AD_MP(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_MP(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_MP(i));
                        %CAS=strjoin(res.MP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.MP.model.set.K, res.MP_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.MP.model.set.K, res.MP_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.MP.model.set.K, res.MP_pred_neighbor(i,1:5));
                        end
                    end
                end                
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.MP=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
            end
            %Predict BP values
            [Lia,Locb] =ismember('bp',lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message = 'Predicting BP values (Deg. C)';

                Desc=train.BP.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting BP values... \n\n			============================================================== \n\n');
                end

                Xtest=Xin(:,train.BP.Desc_i);                
                
                pred = nnrpred(Xtest,train.BP.model.set.train,train.BP.model.set.y,train.BP.model.set.K,train.BP.model.set.dist_type,train.BP.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.BP_exp=NaN(size(Xtest,1),1);
                end
                res.BP_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.BP.model.set.train,Xtest,'auto');
                res.AD_BP=abs(AD.inorout-1)';
                res.AD_BP(round(pred.dc(:,1),3)==0)=1;

                res.AD_index_BP=zeros(size(Xtest,1),1);
                res.Conf_index_BP=zeros(size(Xtest,1),1);
                
                BP_CAS_neighbor=cell(size(Xtest,1),5);
                BP_InChiKey_neighbor=cell(size(Xtest,1),5);
                BP_DTXSID_neighbor=cell(size(Xtest,1),5);
                BP_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                BP_Exp_neighbor=zeros(size(Xtest,1),5);
                BP_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.BP.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.BP.DTXSID);
                        end
                        if Li
                            if Lo>size(train.BP.DTXSID,1)
                                Lo=mod(Lo,size(train.BP.DTXSID,1));
                            end
                            res.BP_exp(i)=train.BP.model.set.y(Lo);
                        end
                    end
                    BP_CAS_neighbor(i,:)=train.BP.CAS(pred.neighbors(i,:));
                    BP_InChiKey_neighbor(i,:)=train.BP.InChiKey(pred.neighbors(i,:));
                    BP_DTXSID_neighbor(i,:)=train.BP.DTXSID(pred.neighbors(i,:));
                    BP_DSSTOXMPID_neighbor(i,:)=train.BP.DSSTOXMPID(pred.neighbors(i,:));
                    BP_Exp_neighbor(i,:)=train.BP.model.set.y(pred.neighbors(i,:));
                    BP_pred_neighbor(i,:)=train.BP.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(BP_Exp_neighbor(i,:),BP_pred_neighbor(i,:));
                    %                 res.Conf_index_BP(i,1)=1/(1+rmse.RMSEC/50);
                    
                    res.AD_index_BP(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_BP(i,1)=((1/(1+sqrt(((BP_Exp_neighbor(i,:)-BP_pred_neighbor(i,:)).^2)*pred.w(i,:)')/50))+res.AD_index_BP(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.BP_pred(i,1)=NaN;
                        res.AD_BP(i)=0;
                        res.AD_index_BP(i)=0;
                        res.Conf_index_BP(i,1)=0;
                    end

                    if neighbors==1
                        res.BP_CAS_neighbor(i,:)=BP_CAS_neighbor(i,:);
                        res.BP_InChiKey_neighbor(i,:)=BP_InChiKey_neighbor(i,:);
                        res.BP_DTXSID_neighbor(i,:)=BP_DTXSID_neighbor(i,:);
                        res.BP_DSSTOXMPID_neighbor(i,:)=BP_DSSTOXMPID_neighbor(i,:);
                        res.BP_Exp_neighbor(i,:)=BP_Exp_neighbor(i,:);
                        res.BP_pred_neighbor(i,:)=BP_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb),'BP experimental= %.3f\n', res.BP_exp(i));
                        end
                        fprintf(output(Locb),'BP predicted= %.3f\n', res.BP_pred(i));
                        if res.AD_BP(i)==1
                            fprintf(output(Locb),'AD: inside\n');
                        else
                            fprintf(output(Locb),'AD: outside\n');
                        end
                        fprintf(output(Locb),'AD_index= %.2f\n', res.AD_index_BP(i));
                        fprintf(output(Locb),'Conf_index= %.2f\n', res.Conf_index_BP(i));
                        %CAS=strjoin(res.BP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BP.model.set.K, res.BP_CAS_neighbor{i,1:5});
                            fprintf(output(Locb),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BP.model.set.K, res.BP_Exp_neighbor(i,1:5));
                            fprintf(output(Locb),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BP.model.set.K, res.BP_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'BP experimental= %.3f\n', res.BP_exp(i));
                        end
                        fprintf(output,'BP predicted= %.3f\n', res.BP_pred(i));
                        if res.AD_BP(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_BP(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_BP(i));
                        %CAS=strjoin(res.BP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BP.model.set.K, res.BP_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BP.model.set.K, res.BP_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BP.model.set.K, res.BP_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.BP=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
                
            end
            %Predict VP values
            [Lia,Locb] =ismember({'vp','logvp'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message = 'Predicting LogVP values (Log10 mmHg)';
                Desc=train.VP.Desc;

                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting VP values in Log mmHg... \n\n			============================================================== \n\n');
                end

                Xtest=Xin(:,train.VP.Desc_i);
                
                pred = nnrpred(Xtest,train.VP.model.set.train,train.VP.model.set.y,train.VP.model.set.K,train.VP.model.set.dist_type,train.VP.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogVP_exp=NaN(size(Xtest,1),1);
                end
                res.LogVP_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.VP.model.set.train,Xtest,'auto');
                res.AD_VP=abs(AD.inorout-1)';
                res.AD_VP(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_VP=zeros(size(Xtest,1),1);
                res.Conf_index_VP=zeros(size(Xtest,1),1);
                
                LogVP_CAS_neighbor=cell(size(Xtest,1),5);
                LogVP_InChiKey_neighbor=cell(size(Xtest,1),5);
                LogVP_DTXSID_neighbor=cell(size(Xtest,1),5);
                LogVP_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogVP_Exp_neighbor=zeros(size(Xtest,1),5);
                LogVP_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.VP.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.VP.DTXSID);
                        end
                        if Li
                            if Lo>size(train.VP.DTXSID,1)
                                Lo=mod(Lo,size(train.VP.DTXSID,1));
                            end
                            res.LogVP_exp(i)=train.VP.model.set.y(Lo);
                        end
                    end
                    LogVP_CAS_neighbor(i,:)=train.VP.CAS(pred.neighbors(i,:));
                    LogVP_InChiKey_neighbor(i,:)=train.VP.InChiKey(pred.neighbors(i,:));
                    LogVP_DTXSID_neighbor(i,:)=train.VP.DTXSID(pred.neighbors(i,:));
                    LogVP_DSSTOXMPID_neighbor(i,:)=train.VP.DSSTOXMPID(pred.neighbors(i,:));
                    LogVP_Exp_neighbor(i,:)=train.VP.model.set.y(pred.neighbors(i,:));
                    LogVP_pred_neighbor(i,:)=train.VP.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogVP_Exp_neighbor(i,:),res.LogVP_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_VP(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_VP(i,1)=((1/(1+sqrt(((LogVP_Exp_neighbor(i,:)-LogVP_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+ res.AD_index_VP(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.LogVP_pred(i,1)=NaN;
                        res.AD_VP(i)=0;
                        res.AD_index_VP(i)=0;
                        res.Conf_index_VP(i,1)=0;
                    end

                    if neighbors==1
                        res.LogVP_CAS_neighbor(i,:)=LogVP_CAS_neighbor(i,:);
                        res.LogVP_InChiKey_neighbor(i,:)=LogVP_InChiKey_neighbor(i,:);
                        res.LogVP_DTXSID_neighbor(i,:)=LogVP_DTXSID_neighbor(i,:);
                        res.LogVP_DSSTOXMPID_neighbor(i,:)=LogVP_DSSTOXMPID_neighbor(i,:);
                        res.LogVP_Exp_neighbor(i,:)=LogVP_Exp_neighbor(i,:);
                        res.LogVP_pred_neighbor(i,:)=LogVP_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogVP experimental= %.3f\n', res.LogVP_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogVP predicted= %.3f\n', res.LogVP_pred(i));
                        if res.AD_VP(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_VP(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_VP(i));
                        %CAS=strjoin(res.LogVP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.VP.model.set.K, res.LogVP_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.VP.model.set.K, res.LogVP_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.VP.model.set.K, res.LogVP_pred_neighbor(i,1:5));
                        end
                                                
                    elseif strcmpi(ext,'.txt') && sep==0
                                                
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogVP experimental= %.3f\n', res.LogVP_exp(i));
                        end
                        fprintf(output,'LogVP predicted= %.3f\n', res.LogVP_pred(i));
                        if res.AD_VP(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_VP(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_VP(i));
                        %CAS=strjoin(res.LogVP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.VP.model.set.K, res.LogVP_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.VP.model.set.K, res.LogVP_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.VP.model.set.K, res.LogVP_pred_neighbor(i,1:5));
                        end
                        
                    end
                end                
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.VP=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
                
            end
            
            %Predict WS values
            %case {'ws','logws'}
            [Lia,Locb] =ismember({'ws','logws'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message = 'Predicting LogWS values (Log10 M)';
                Desc=train.WS.Desc;

                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogWS values... \n\n			============================================================== \n\n');
                end

                Xtest=Xin(:,train.WS.Desc_i);
                
                pred = nnrpred(Xtest,train.WS.model.set.train,train.WS.model.set.y,train.WS.model.set.K,train.WS.model.set.dist_type,train.WS.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogWS_exp=NaN(size(Xtest,1),1);
                end
                res.LogWS_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.WS.model.set.train,Xtest,'auto');
                res.AD_WS=abs(AD.inorout-1)';
                res.AD_WS(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_WS=zeros(size(Xtest,1),1);
                res.Conf_index_WS=zeros(size(Xtest,1),1);
                
                LogWS_CAS_neighbor=cell(size(Xtest,1),5);
                LogWS_InChiKey_neighbor=cell(size(Xtest,1),5);
                LogWS_DTXSID_neighbor=cell(size(Xtest,1),5);
                LogWS_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogWS_Exp_neighbor=zeros(size(Xtest,1),5);
                LogWS_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.WS.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.WS.DTXSID);
                        end
                        if Li
                            if Lo>size(train.WS.DTXSID,1)
                                Lo=mod(Lo,size(train.WS.DTXSID,1));
                            end
                            res.LogWS_exp(i)=train.WS.model.set.y(Lo);
                        end
                    end
                    LogWS_CAS_neighbor(i,:)=train.WS.CAS(pred.neighbors(i,:));
                    LogWS_InChiKey_neighbor(i,:)=train.WS.InChiKey(pred.neighbors(i,:));
                    LogWS_DTXSID_neighbor(i,:)=train.WS.DTXSID(pred.neighbors(i,:));
                    LogWS_DSSTOXMPID_neighbor(i,:)=train.WS.DSSTOXMPID(pred.neighbors(i,:));
                    LogWS_Exp_neighbor(i,:)=train.WS.model.set.y(pred.neighbors(i,:));
                    LogWS_pred_neighbor(i,:)=train.WS.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogWS_Exp_neighbor(i,:),res.LogWS_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_WS(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_WS(i,1)=((1/(1+sqrt(((LogWS_Exp_neighbor(i,:)-LogWS_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_WS(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.LogWS_pred(i,1)=NaN;
                        res.AD_WS(i)=0;
                        res.AD_index_WS(i)=0;
                        res.Conf_index_WS(i,1)=0;
                    end

                    if neighbors==1
                        res.LogWS_CAS_neighbor(i,:)=LogWS_CAS_neighbor(i,:);
                        res.LogWS_InChiKey_neighbor(i,:)=LogWS_InChiKey_neighbor(i,:);
                        res.LogWS_DTXSID_neighbor(i,:)=LogWS_DTXSID_neighbor(i,:);
                        res.LogWS_DSSTOXMPID_neighbor(i,:)=LogWS_DSSTOXMPID_neighbor(i,:);
                        res.LogWS_Exp_neighbor(i,:)=LogWS_Exp_neighbor(i,:);
                        res.LogWS_pred_neighbor(i,:)=LogWS_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogWS experimental= %.3f\n', res.LogWS_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogWS predicted= %.3f\n', res.LogWS_pred(i));
                        if res.AD_WS(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_WS(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_WS(i));
                        %CAS=strjoin(res.LogWS_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.WS.model.set.K, res.LogWS_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.WS.model.set.K, res.LogWS_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.WS.model.set.K, res.LogWS_pred_neighbor(i,1:5));
                        end
                                                
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogWS experimental= %.3f\n', res.LogWS_exp(i));
                        end
                        fprintf(output,'LogWS predicted= %.3f\n', res.LogWS_pred(i));
                        if res.AD_WS(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_WS(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_WS(i));
                        %CAS=strjoin(res.LogWS_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.WS.model.set.K, res.LogWS_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.WS.model.set.K, res.LogWS_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.WS.model.set.K, res.LogWS_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
 
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.WS=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
                
            end
            
            %Predict HL values
            %case {'hl','loghl'}
            [Lia,Locb] =ismember({'hl','loghl'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message = 'Predicting LogHL values (Log10 atm-m3/V)';
                
                Desc=train.HL.Desc;

                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogHL values... \n\n			============================================================== \n\n');
                end

                Xtest=Xin(:,train.HL.Desc_i);
                
                pred = nnrpred(Xtest,train.HL.model.set.train,train.HL.model.set.y,train.HL.model.set.K,train.HL.model.set.dist_type,train.HL.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogHL_exp=NaN(size(Xtest,1),1);
                end
                res.LogHL_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.HL.model.set.train,Xtest,'auto');
                res.AD_HL=abs(AD.inorout-1)';
                res.AD_HL(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_HL=zeros(size(Xtest,1),1);
                res.Conf_index_HL=zeros(size(Xtest,1),1);
                
                HL_CAS_neighbor=cell(size(Xtest,1),5);
                HL_InChiKey_neighbor=cell(size(Xtest,1),5);
                HL_DTXSID_neighbor=cell(size(Xtest,1),5);
                HL_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogHL_Exp_neighbor=zeros(size(Xtest,1),5);
                LogHL_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.HL.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.HL.DTXSID);
                        end
                        if Li
                            if Lo>size(train.HL.DTXSID,1)
                                Lo=mod(Lo,size(train.HL.DTXSID,1));
                            end
                            res.LogHL_exp(i)=train.HL.model.set.y(Lo);
                        end
                    end
                    HL_CAS_neighbor(i,:)=train.HL.CAS(pred.neighbors(i,:));
                    HL_InChiKey_neighbor(i,:)=train.HL.InChiKey(pred.neighbors(i,:));
                    HL_DTXSID_neighbor(i,:)=train.HL.DTXSID(pred.neighbors(i,:));
                    HL_DSSTOXMPID_neighbor(i,:)=train.HL.DSSTOXMPID(pred.neighbors(i,:));
                    LogHL_Exp_neighbor(i,:)=train.HL.model.set.y(pred.neighbors(i,:));
                    LogHL_pred_neighbor(i,:)=train.HL.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogHL_Exp_neighbor(i,:),res.LogHL_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_HL(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_HL(i,1)=((1/(1+sqrt(((LogHL_Exp_neighbor(i,:)-LogHL_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_HL(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.LogHL_pred(i,1)=NaN;
                        res.AD_HL(i)=0;
                        res.AD_index_HL(i)=0;
                        res.Conf_index_HL(i,1)=0;
                    end
                    
                    if neighbors==1
                        res.HL_CAS_neighbor(i,:)=HL_CAS_neighbor(i,:);
                        res.HL_InChiKey_neighbor(i,:)=HL_InChiKey_neighbor(i,:);
                        res.HL_DTXSID_neighbor(i,:)=HL_DTXSID_neighbor(i,:);
                        res.HL_DSSTOXMPID_neighbor(i,:)=HL_DSSTOXMPID_neighbor(i,:);
                        res.LogHL_Exp_neighbor(i,:)=LogHL_Exp_neighbor(i,:);
                        res.LogHL_pred_neighbor(i,:)=LogHL_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogHL experimental= %.3f\n', res.LogHL_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogHL predicted= %.3f\n', res.LogHL_pred(i));
                        if res.AD_HL(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_HL(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_HL(i));
                        %CAS=strjoin(res.HL_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.HL.model.set.K, res.HL_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.HL.model.set.K, res.LogHL_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.HL.model.set.K, res.LogHL_pred_neighbor(i,1:5));
                        end                       
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogHL experimental= %.3f\n', res.LogHL_exp(i));
                        end
                        fprintf(output,'LogHL predicted= %.3f\n', res.LogHL_pred(i));
                        if res.AD_HL(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_HL(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_HL(i));
                        %CAS=strjoin(res.HL_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.HL.model.set.K, res.HL_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.HL.model.set.K, res.LogHL_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.HL.model.set.K, res.LogHL_pred_neighbor(i,1:5));
                        end
                        
                    end
                end               
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                  
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.HL=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
                
            end
            
            %Predict RT values
            %case {'rt'}
            [Lia,Locb] =ismember('rt',lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message = 'Predicting RT values (Mins.)';
                
                Desc=train.RT.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting RT values... \n\n			==============================================================  \n\n');
                end

                Xtest=Xin(:,train.RT.Desc_i);
                
                pred = nnrpred(Xtest,train.RT.model.set.train,train.RT.model.set.y,train.RT.model.set.K,train.RT.model.set.dist_type,train.RT.model.set.scal);
                pred.D=diag(pred.D);
                predpls=plstest(Xtest,train.RT.model);
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.RT_exp=NaN(size(Xtest,1),1);
                end
                res.RT_pred(:,1)=predpls.yc;
                AD=classical_leverage(train.RT.model.set.train,Xtest,'auto');
                res.AD_RT=abs(AD.inorout-1)';
                res.AD_RT(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_RT=zeros(size(Xtest,1),1);
                res.Conf_index_RT=zeros(size(Xtest,1),1);
                
                RT_CAS_neighbor=cell(size(Xtest,1),5);
                RT_DTXSID_neighbor=cell(size(Xtest,1),5);
                RT_Exp_neighbor=zeros(size(Xtest,1),5);
                RT_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.RT.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.RT.DTXSID);
                        end
                        if Li
                            if Lo>size(train.RT.DTXSID,1)
                                Lo=mod(Lo,size(train.RT.DTXSID,1));
                            end
                            res.RT_exp(i)=train.RT.model.set.y(Lo);
                        end
                    end
                    RT_CAS_neighbor(i,:)=train.RT.CAS(pred.neighbors(i,:));
                    RT_DTXSID_neighbor(i,:)=train.RT.DTXSID(pred.neighbors(i,:));
                    RT_Exp_neighbor(i,:)=train.RT.model.set.y(pred.neighbors(i,:));
                    RT_pred_neighbor(i,:)=train.RT.model.yc(pred.neighbors(i,:));
                    
                    
                    res.AD_index_RT(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_RT(i,1)=((1/(1+sqrt(((RT_Exp_neighbor(i,:)-RT_pred_neighbor(i,:)).^2)*pred.w(i,:)')/4.5))+res.AD_index_RT(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.RT_pred(i,1)=NaN;
                        res.AD_RT(i)=0;
                        res.AD_index_RT(i)=0;
                        res.Conf_index_RT(i,1)=0;
                    end
                    if res.RT_pred(i,1)<0
                        res.RT_pred(i,1)=0;
                        res.AD_RT(i)=0;
                    end

                    if neighbors==1
                        res.RT_CAS_neighbor(i,:)=RT_CAS_neighbor(i,:);
                        res.RT_DTXSID_neighbor(i,:)=RT_DTXSID_neighbor(i,:);
                        res.RT_Exp_neighbor(i,:)=RT_Exp_neighbor(i,:);
                        res.RT_pred_neighbor(i,:)=RT_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'RT experimental= %.3f\n', res.RT_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'RT predicted= %.3f\n', res.RT_pred(i));
                        if res.AD_RT(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_RT(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_RT(i));
                        %CAS=strjoin(res.RT_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.RT.model.set.K, res.RT_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.RT.model.set.K, res.RT_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.RT.model.set.K, res.RT_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'RT experimental= %.3f\n', res.RT_exp(i));
                        end
                        fprintf(output,'RT predicted= %.3f\n', res.RT_pred(i));
                        if res.AD_RT(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_RT(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_RT(i));
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.RT.model.set.K, res.RT_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.RT.model.set.K, res.RT_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.RT.model.set.K, res.RT_pred_neighbor(i,1:5));
                        end
                        
                    end
                end                
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    

                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.RT=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('predpls');
                clear('AD');
                %end clean memory
                
            end
            
            %Predict KOA values
            %case {'koa','logkoa'}
            [Lia,Locb] =ismember({'koa','logkoa'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting LogKOA values (Log10)';
                Desc=train.KOA.Desc;

                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogKOA values... \n\n			==============================================================  \n\n');
                end

                Xtest=Xin(:,train.KOA.Desc_i);
                
                pred = nnrpred(Xtest,train.KOA.model.set.train,train.KOA.model.set.y,train.KOA.model.set.K,train.KOA.model.set.dist_type,train.KOA.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogKOA_exp=NaN(size(Xtest,1),1);
                end
                res.LogKOA_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.KOA.model.set.train,Xtest,'auto');
                res.AD_KOA=abs(AD.inorout-1)';
                res.AD_KOA(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_KOA=zeros(size(Xtest,1),1);
                res.Conf_index_KOA=zeros(size(Xtest,1),1);
                
                KOA_CAS_neighbor=cell(size(Xtest,1),5);
                KOA_InChiKey_neighbor=cell(size(Xtest,1),5);
                KOA_DTXSID_neighbor=cell(size(Xtest,1),5);
                KOA_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogKOA_Exp_neighbor=zeros(size(Xtest,1),5);
                LogKOA_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.KOA.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.KOA.DTXSID);
                        end
                        if Li
                            if Lo>size(train.KOA.DTXSID,1)
                                Lo=mod(Lo,size(train.KOA.DTXSID,1));
                            end
                            res.LogKOA_exp(i)=train.KOA.model.set.y(Lo);
                        end
                    end
                    KOA_CAS_neighbor(i,:)=train.KOA.CAS(pred.neighbors(i,:));
                    KOA_InChiKey_neighbor(i,:)=train.KOA.InChiKey(pred.neighbors(i,:));
                    KOA_DTXSID_neighbor(i,:)=train.KOA.DTXSID(pred.neighbors(i,:));
                    KOA_DSSTOXMPID_neighbor(i,:)=train.KOA.DSSTOXMPID(pred.neighbors(i,:));
                    LogKOA_Exp_neighbor(i,:)=train.KOA.model.set.y(pred.neighbors(i,:));
                    LogKOA_pred_neighbor(i,:)=train.KOA.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogKOA_Exp_neighbor(i,:),res.LogKOA_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_KOA(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_KOA(i,1)=((1/(1+sqrt(((LogKOA_Exp_neighbor(i,:)-LogKOA_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_KOA(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.LogKOA_pred(i,1)=NaN;
                        res.AD_KOA(i)=0;
                        res.AD_index_KOA(i)=0;
                        res.Conf_index_KOA(i,1)=0;    
                    end
                    
                    
                    if neighbors==1
                        res.KOA_CAS_neighbor(i,:)=KOA_CAS_neighbor(i,:);
                        res.KOA_InChiKey_neighbor(i,:)=KOA_InChiKey_neighbor(i,:);
                        res.KOA_DTXSID_neighbor(i,:)=KOA_DTXSID_neighbor(i,:);
                        res.KOA_DSSTOXMPID_neighbor(i,:)=KOA_DSSTOXMPID_neighbor(i,:);
                        res.LogKOA_Exp_neighbor(i,:)=LogKOA_Exp_neighbor(i,:);
                        res.LogKOA_pred_neighbor(i,:)=LogKOA_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogKOA experimental= %.3f\n', res.LogKOA_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogKOA predicted= %.3f\n', res.LogKOA_pred(i));
                        if res.AD_KOA(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_KOA(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_KOA(i));
                        %CAS=strjoin(res.KOA_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KOA.model.set.K, res.KOA_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KOA.model.set.K, res.LogKOA_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KOA.model.set.K, res.LogKOA_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogKOA experimental= %.3f\n', res.LogKOA_exp(i));
                        end
                        fprintf(output,'LogKOA predicted= %.3f\n', res.LogKOA_pred(i));
                        if res.AD_KOA(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_KOA(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_KOA(i));
                        %CAS=strjoin(res.KOA_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KOA.model.set.K, res.KOA_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KOA.model.set.K, res.LogKOA_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KOA.model.set.K, res.LogKOA_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.KOA=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
            end
            
            %Predict pka values
            %case {'pka'}
            [Lia,Locb] =ismember({'pka','logd'},lower(prop));
            if find(Lia)
                if Lia(1)
                    step=step+1;
                    d.Value=step/steps;
                end
                if d.CancelRequested
                    msgbox('Canceled by user', 'Error','error');
                    return;
                end
                d.Message='Predicting pKa values';
                                
                Desc=train.pKa.Desc;
                %             Desc_a=train.pKa.Desc_a;
                %             Desc_b=train.pKa.Desc_b;

                
                if strcmpi(ext,'.txt') && sep==0 && Lia(1)
                    fprintf(output,'\n\n\t\t\t\t\t Predicting pKa values... \n\n			==============================================================  \n\n');
                end
                                
                Xtest=Xin(:,train.pKa.Desc_i);
                Xtest_a=table2array(XinFP(:,train.pKa.Desc_ai));
                Xtest_b=table2array(XinFP(:,train.pKa.Desc_bi));
                                
                pred = knnpred(Xtest,train.pKa.model.set.train,train.pKa.model.set.class,train.pKa.model.set.K,train.pKa.model.set.dist_type,train.pKa.model.set.param.pret_type);
                pred.D=diag(pred.D);
                pKa_a(:,1)=svmpredict([1:1:length(Xtest_a(:,1))]',Xtest_a,train.pKa.model_a,'-q');
                %AD_a = nnrpred(Xtest_a,train.pKa_a.model.set.train,train.pKa_a.model.set.y,train.pka_a.model.set.K,train.pka_a.model.set.dist_type,train.pka_a.model.set.param.pret_type);
                
                pKa_b(:,1)=svmpredict([1:1:length(Xtest_b(:,1))]',Xtest_b,train.pKa.model_b,'-q');
                %AD_b = nnrpred(Xtest_b,train.pka_b.model.set.train,train.pka_b.model.set.y,train.pka_b.model.set.K,train.pka_b.model.set.dist_type,train.pka_b.model.set.param.pret_type);
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.pKa_a_exp=NaN(size(Xtest,1),1);
                    res.pKa_b_exp=NaN(size(Xtest,1),1);
                end
                res.ionization=zeros(size(Xtest,1),1);
                pKa_ac_ba_amp=pred.class_pred;
                res.pKa_a_pred=pKa_a;
                res.pKa_b_pred=pKa_b;
                
                AD=classical_leverage(train.pKa.model.set.train,Xtest,'auto');
                res.AD_pKa=abs(AD.inorout-1)';
                res.AD_pKa(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_pKa=zeros(size(Xtest,1),1);
                res.Conf_index_pKa=zeros(size(Xtest,1),1);
                
                pKa_CAS_neighbor=cell(size(Xtest,1),3);
                pKa_InChiKey_neighbor=cell(size(Xtest,1),3);
                pKa_DTXSID_neighbor=cell(size(Xtest,1),3);
                %pKa_DSSTOXMPID_neighbor=cell(size(Xtest,1),3);
                pKa_Exp_neighbor=zeros(size(Xtest,1),3);
                pKa_pred_neighbor=zeros(size(Xtest,1),3);                
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.pKa.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.pKa.DTXSID);
                        end
                        if Li
                            res.pKa_a_exp(i,1)=train.pKa.model.set.y_exp(Lo,1);
                            res.pKa_b_exp(i,1)=train.pKa.model.set.y_exp(Lo,2);
                        end
                    end
                    if sum(Xin(i,13:14))-sum(Xin(i,731:732))==0
                        pKa_ac_ba_amp(i)=NaN;
                        res.ionization(i)=0;
                        res.pKa_a_pred(i)=NaN;
                        res.pKa_b_pred(i)=NaN;
                        
                    else
                        
                        if pred.class_pred(i)==1
                            res.pKa_b_pred(i,1)=NaN;
                            res.ionization(i)=1;
                        elseif pred.class_pred(i)==2
                            res.pKa_a_pred(i,1)=NaN;
                            res.ionization(i)=1;
                        elseif pred.class_pred(i)==3
                            res.ionization(i)=2;
                            
                        end
                    end
                                        
                    pKa_CAS_neighbor(i,:)=train.pKa.CAS(pred.neighbors(i,:));
                    pKa_InChiKey_neighbor(i,:)=train.pKa.InChiKey(pred.neighbors(i,:));
                    pKa_DTXSID_neighbor(i,:)=train.pKa.DTXSID(pred.neighbors(i,:));
                    %                 pKa_DSSTOXMPID_neighbor(i,:)=train.pKa.DSSTOXMPID(pred.neighbors(i,:));
                    pKa_Exp_neighbor(i,:)=train.pKa.model.set.y(pred.neighbors(i,:));
                    pKa_pred_neighbor(i,:)=train.pKa.model.set.yc(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogKOA_Exp_neighbor(i,:),res.LogKOA_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_pKa(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_pKa(i,1)=((1/(1+sqrt(((pKa_Exp_neighbor(i,:)-pKa_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_pKa(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.pKa_a_pred(i,1)=NaN;
                        res.pKa_b_pred(i,1)=NaN;
                        res.AD_pKa(i,1)=0;
                        res.AD_index_pKa(i,1)=0;
                        res.Conf_index_pKa(i,1)=0;
                    end

                    if neighbors==1
                        res.pKa_CAS_neighbor(i,:)=pKa_CAS_neighbor(i,:);
                        res.pKa_InChiKey_neighbor(i,:)=pKa_InChiKey_neighbor(i,:);
                        res.pKa_DTXSID_neighbor(i,:)=pKa_DTXSID_neighbor(i,:);
                        %res.pKa_DSSTOXMPID_neighbor(i,:)=pKa_DSSTOXMPID_neighbor(i,:);
                        res.pKa_Exp_neighbor(i,:)=pKa_Exp_neighbor(i,:);
                        res.pKa_pred_neighbor(i,:)=pKa_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1 && Lia(1)
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(1)),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(1)),'pKa acidic and basic experimental= %.3f, %.3f\n', res.pKa_a_exp(i),res.pKa_b_exp(i));
                        end
                        fprintf(output(Locb(1)),'pKa acidic and basic predicted= %.3f, %.3f\n', res.pKa_a_pred(i),res.pKa_b_pred(i));
                        if res.AD_pKa(i)==1
                            fprintf(output(Locb(1)),'AD: inside\n');
                        else
                            fprintf(output(Locb(1)),'AD: outside\n');
                        end
                        fprintf(output(Locb(1)),'AD_index= %.2f\n', res.AD_index_pKa(i));
                        fprintf(output(Locb(1)),'Conf_index= %.2f\n', res.Conf_index_pKa(i));
                        %CAS=strjoin(res.RT_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(1)),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.pKa.model.set.K, res.pKa_CAS_neighbor{i,1:3});
                            fprintf(output(Locb(1)),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.pKa.model.set.K, res.pKa_Exp_neighbor(i,1:3));
                            fprintf(output(Locb(1)),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.pKa.model.set.K, res.pKa_pred_neighbor(i,1:3));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0 && Lia(1)
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'pKa acidic and basic experimental= %.3f, %.3f\n', res.pKa_a_exp(i),res.pKa_b_exp(i));
                        end
                        fprintf(output,'pKa acidic and basic predicted= %.3f, %.3f\n', res.pKa_a_pred(i),res.pKa_b_pred(i));
                        if res.AD_pKa(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_pKa(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_pKa(i));
                        %CAS=strjoin(res.KOA_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.pKa.model.set.K, res.pKa_CAS_neighbor{i,1:3});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.pKa.model.set.K, res.pKa_Exp_neighbor(i,1:3));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.pKa.model.set.K, res.pKa_pred_neighbor(i,1:3));
                        end
                        
                    end
                end                
                
                if sep==1 && strcmpi(ext,'.csv') && Lia(1)
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(1)},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(1)));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv') && Lia(1)
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.pKa=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('XinFP');
                clear('Xtest_a');
                clear('Xtest_b');
                clear('pred');
                clear('AD');
                %end clean memory
            end
            %Predict LogD values
            %case 'logd'
            [Lia,Locb] =ismember('logd',lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                 d.Message='Predicting LogD values (Log10)';

                res.MoleculeID=MoleculeNames;
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogD values... \n\n			==============================================================  \n\n');
                end
                if sep==1
                    
                    res.LogD55_pred=resf.LogP.LogP_pred;
                    res.LogD74_pred=resf.LogP.LogP_pred;
                    res.AD_LogD=resf.LogP.AD_LogP+resf.pKa.AD_pKa;
                    res.AD_LogD(find(res.AD_LogD==1))=0;
                    res.AD_LogD(find(res.AD_LogD==2))=1;
                    res.AD_index_LogD=0.5*resf.pKa.AD_index_pKa+0.5*resf.LogP.AD_index_LogP;
                    res.Conf_index_LogD=0.5*resf.pKa.Conf_index_pKa+0.5*resf.LogP.Conf_index_LogP;
                    
                    if neighbors==1
                        res.LogD_CAS_neighbor=resf.LogP.LogP_CAS_neighbor;
                        res.LogD_InChiKey_neighbor=resf.LogP.LogP_InChiKey_neighbor;
                        res.LogD_DTXSID_neighbor=resf.LogP.LogP_DTXSID_neighbor;
                        res.LogD_DSSTOXMPID_neighbor=resf.LogP.LogP_DSSTOXMPID_neighbor;
                        %res.LogD_Exp_neighbor=LogP_Exp_neighbor;
                        %res.LogD_pred_neighbor=LogP_pred_neighbor;
                    end
                    
                    for i=1:length(res.LogD55_pred)
                        
                        if pKa_ac_ba_amp(i)==1
                            res.LogD55_pred(i,1)=resf.LogP.LogP_pred(i,1)-log10(1+10^(5.5-resf.pKa.pKa_a_pred(i,1)));
                            res.LogD74_pred(i,1)=resf.LogP.LogP_pred(i,1)-log10(1+10^(7.4-resf.pKa.pKa_a_pred(i,1)));
                        elseif pKa_ac_ba_amp(i)==2
                            res.LogD55_pred(i,1)=resf.LogP.LogP_pred(i,1)-log10(1+10^(resf.pKa.pKa_b_pred(i,1)-5.5));
                            res.LogD74_pred(i,1)=resf.LogP.LogP_pred(i,1)-log10(1+10^(resf.pKa.pKa_b_pred(i,1)-7.4));
                        elseif pKa_ac_ba_amp(i)==3
                            res.LogD55_pred(i,1)=resf.LogP.LogP_pred(i,1)-log10(1+10^abs(0.5*resf.pKa.pKa_a_pred(i,1)+0.5*resf.pKa.pKa_b_pred(i,1)-5.5));
                            res.LogD74_pred(i,1)=resf.LogP.LogP_pred(i,1)-log10(1+10^abs(0.5*resf.pKa.pKa_a_pred(i,1)+0.5*resf.pKa.pKa_b_pred(i,1)-7.4));
                        end
                        
                        if strcmpi(ext,'.txt')
                            fprintf(output(Locb(1)),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(1)),'LogD pH 5.5 predicted= %.3f\n', res.LogD55_pred(i));
                            fprintf(output(Locb(1)),'LogD pH 7.4 predicted= %.3f\n', res.LogD74_pred(i));
                            if res.AD_LogD(i)==1
                                fprintf(output(Locb(1)),'AD: inside\n');
                            else
                                fprintf(output(Locb(1)),'AD: outside\n');
                            end
                            fprintf(output(Locb(1)),'AD_index= %.2f\n', res.AD_index_LogD(i));
                            fprintf(output(Locb(1)),'Conf_index= %.2f\n', res.Conf_index_LogD(i));
                            if neighbors==1
                                fprintf(output(Locb(1)),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.LogP.model.set.K,resf.LogP.LogP_CAS_neighbor{i,1:5});
                                %fprintf(output(Locb(1)),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.LogP.model.set.K, res.LogP_Exp_neighbor(i,1:5));
                                %fprintf(output(Locb(1)),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.LogP.model.set.K, res.LogP_pred_neighbor(i,1:5));
                            end
                        end
                    end
                    
                    if strcmpi(ext,'.csv')
                        T=struct2table(res);
                        writetable(T,FileOut{Locb(1)},'Delimiter',',');%,'QuoteStrings',true);
                        fclose(output(Locb(1)));
                        clear('T');
                    end
                    resf.LogD=res;
                    clear('res');
                                       
                else
                    res.LogD55_pred=res.LogP_pred;
                    res.LogD74_pred=res.LogP_pred;
                    res.AD_LogD=res.AD_LogP+res.AD_pKa;
                    res.AD_LogD(find(res.AD_LogD==1))=0;
                    res.AD_LogD(find(res.AD_LogD==2))=1;
                    res.AD_index_LogD=0.5*res.AD_index_pKa+0.5*res.AD_index_LogP;
                    res.Conf_index_LogD=0.5*res.Conf_index_pKa+0.5*res.Conf_index_LogP;
                    
                    if neighbors==1
                        res.LogD_CAS_neighbor=res.LogP_CAS_neighbor;
                        res.LogD_InChiKey_neighbor=res.LogP_InChiKey_neighbor;
                        res.LogD_DTXSID_neighbor=res.LogP_DTXSID_neighbor;
                        res.LogD_DSSTOXMPID_neighbor=res.LogP_DSSTOXMPID_neighbor;
                        %res.LogD_Exp_neighbor=LogP_Exp_neighbor;
                        %res.LogD_pred_neighbor=LogP_pred_neighbor;
                    end
                    
                    for i=1:length(res.LogD55_pred)
                        if pKa_ac_ba_amp(i)==1
                            res.LogD55_pred(i,1)=res.LogP_pred(i,1)-log10(1+10^(5.5-res.pKa_a_pred(i,1)));
                            res.LogD74_pred(i,1)=res.LogP_pred(i,1)-log10(1+10^(7.4-res.pKa_a_pred(i,1)));
                        elseif pKa_ac_ba_amp(i)==2
                            res.LogD55_pred(i,1)=res.LogP_pred(i,1)-log10(1+10^(res.pKa_b_pred(i,1)-5.5));
                            res.LogD74_pred(i,1)=res.LogP_pred(i,1)-log10(1+10^(res.pKa_b_pred(i,1)-7.4));
                        elseif pKa_ac_ba_amp(i)==3
                            res.LogD55_pred(i,1)=res.LogP_pred(i,1)-log10(1+10^abs(0.5*res.pKa_a_pred(i,1)+0.5*res.pKa_b_pred(i,1)-5.5));
                            res.LogD74_pred(i,1)=res.LogP_pred(i,1)-log10(1+10^abs(0.5*res.pKa_a_pred(i,1)+0.5*res.pKa_b_pred(i,1)-7.4));
                        end
                        
                        if strcmpi(ext,'.txt')
                            fprintf(output,'\t Molecule %s:\n',res.MoleculeID{i});
                            fprintf(output,'LogD pH 5.5 predicted= %.3f\n', res.LogD55_pred(i));
                            fprintf(output,'LogD pH 7.4 predicted= %.3f\n', res.LogD74_pred(i));
                            if res.AD_LogD(i)==1
                                fprintf(output,'AD: inside\n');
                            else
                                fprintf(output,'AD: outside\n');
                            end
                            fprintf(output,'AD_index= %.2f\n', res.AD_index_LogD(i));
                            fprintf(output,'Conf_index= %.2f\n', res.Conf_index_LogD(i));
                            if neighbors==1
                                fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.LogP.model.set.K, res.LogP_CAS_neighbor{i,1:5});
                                %fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.LogP.model.set.K, res.LogP_Exp_neighbor(i,1:5));
                                %fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.LogP.model.set.K, res.LogP_pred_neighbor(i,1:5));
                            end
                            
                        end
                        
                        
                    end
                end
                
            end
            
            %  Env. Fate Endpoints

%---------- Env. Fate Endpoints ----------\n');

            %Predict AOH values
            %case {'aop','logoh','aoh'}
            [Lia,Locb] =ismember({'aop','logoh','aoh'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting LogOH values (Log10 cm3/molecule-sec)';
                Desc=train.AOH.Desc;
               
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogOH values... \n\n			============================================================== \n\n');
                end
 
                Xtest=Xin(:,train.AOH.Desc_i);
                
                pred = nnrpred(Xtest,train.AOH.model.set.train,train.AOH.model.set.y,train.AOH.model.set.K,train.AOH.model.set.dist_type,train.AOH.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogOH_exp=NaN(size(Xtest,1),1);
                end
                res.LogOH_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.AOH.model.set.train,Xtest,'auto');
                res.AD_AOH=abs(AD.inorout-1)';
                res.AD_AOH(round(pred.dc(:,1),3)==0)=1;
                
                %res.AD_index=1./(1+nanmedian(pred.dc,2));
                
                
                %             res.AD_index=1./(1+median(pred.dc,2));
                %             if isnan(res.AD_index)
                %                 res.AD_index=0;
                %             end
                
                res.AD_index_AOH=zeros(size(Xtest,1),1);
                res.Conf_index_AOH=zeros(size(Xtest,1),1);
                
                AOH_CAS_neighbor=cell(size(Xtest,1),5);
                AOH_InChiKey_neighbor=cell(size(Xtest,1),5);
                AOH_DTXSID_neighbor=cell(size(Xtest,1),5);
                AOH_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogOH_Exp_neighbor=zeros(size(Xtest,1),5);
                LogOH_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.AOH.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.AOH.DTXSID);
                        end
                        if Li
                            if Lo>size(train.AOH.DTXSID,1)
                                Lo=mod(Lo,size(train.AOH.DTXSID,1));
                            end
                            res.LogOH_exp(i)=train.AOH.model.set.y(Lo);
                        end
                    end
                    AOH_CAS_neighbor(i,:)=train.AOH.CAS(pred.neighbors(i,:));
                    AOH_InChiKey_neighbor(i,:)=train.AOH.InChiKey(pred.neighbors(i,:));
                    AOH_DTXSID_neighbor(i,:)=train.AOH.DTXSID(pred.neighbors(i,:));
                    AOH_DSSTOXMPID_neighbor(i,:)=train.AOH.DSSTOXMPID(pred.neighbors(i,:));
                    LogOH_Exp_neighbor(i,:)=train.AOH.model.set.y(pred.neighbors(i,:));
                    LogOH_pred_neighbor(i,:)=train.AOH.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogOH_Exp_neighbor(i,:),res.LogOH_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_AOH(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_AOH(i,1)=((1/(1+sqrt(((LogOH_Exp_neighbor(i,:)-LogOH_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_AOH(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.LogOH_pred(i,1)=NaN;
                        res.AD_AOH(i)=0;
                        res.AD_index_AOH(i)=0;
                        res.Conf_index_AOH(i,1)=0;
                    end

                    if neighbors==1
                        res.AOH_CAS_neighbor(i,:)=AOH_CAS_neighbor(i,:);
                        res.AOH_InChiKey_neighbor(i,:)=AOH_InChiKey_neighbor(i,:);
                        res.AOH_DTXSID_neighbor(i,:)=AOH_DTXSID_neighbor(i,:);
                        res.AOH_DSSTOXMPID_neighbor(i,:)=AOH_DSSTOXMPID_neighbor(i,:);
                        res.LogOH_Exp_neighbor(i,:)=LogOH_Exp_neighbor(i,:);
                        res.LogOH_pred_neighbor(i,:)=LogOH_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogOH experimental= %.3f\n', res.LogOH_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogOH predicted= %.3f\n', res.LogOH_pred(i));
                        if res.AD_AOH(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_AOH(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_AOH(i));
                        %CAS=strjoin(res.AOH_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.AOH.model.set.K, res.AOH_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.AOH.model.set.K, res.LogOH_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.AOH.model.set.K, res.LogOH_pred_neighbor(i,1:5));
                        end
                                                
                    elseif strcmpi(ext,'.txt') && sep==0                        
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogOH experimental= %.3f\n', res.LogOH_exp(i));
                        end
                        fprintf(output,'LogOH predicted= %.3f\n', res.LogOH_pred(i));
                        if res.AD_AOH(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_AOH(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_AOH(i));
                        %CAS=strjoin(res.AOH_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.AOH.model.set.K, res.AOH_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.AOH.model.set.K, res.LogOH_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.AOH.model.set.K, res.LogOH_pred_neighbor(i,1:5));
                        end
                        
                    end
                end                
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.AOH=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
                
            end
            
            %Predict BCF values
            %case {'bcf', 'logbcf'}
            [Lia,Locb] =ismember({'bcf','logbcf'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting LogBCF values (Log10)';
                
                Desc=train.BCF.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogBCF values... \n\n			============================================================== \n\n');
                end

                Xtest=Xin(:,train.BCF.Desc_i);
                
                pred = nnrpred(Xtest,train.BCF.model.set.train,train.BCF.model.set.y,train.BCF.model.set.K,train.BCF.model.set.dist_type,train.BCF.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogBCF_exp=NaN(size(Xtest,1),1);
                end
                res.LogBCF_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.BCF.model.set.train,Xtest,'auto');
                res.AD_BCF=abs(AD.inorout-1)';
                res.AD_BCF(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_BCF=zeros(size(Xtest,1),1);
                res.Conf_index_BCF=zeros(size(Xtest,1),1);
                
                LogBCF_CAS_neighbor=cell(size(Xtest,1),5);
                LogBCF_InChiKey_neighbor=cell(size(Xtest,1),5);
                LogBCF_DTXSID_neighbor=cell(size(Xtest,1),5);
                LogBCF_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogBCF_Exp_neighbor=zeros(size(Xtest,1),5);
                LogBCF_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.BCF.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.BCF.DTXSID);
                        end
                        if Li
                            if Lo>size(train.BCF.DTXSID,1)
                                Lo=mod(Lo,size(train.BCF.DTXSID,1));
                            end
                            res.LogBCF_exp(i)=train.BCF.model.set.y(Lo);
                        end
                    end
                    LogBCF_CAS_neighbor(i,:)=train.BCF.CAS(pred.neighbors(i,:));
                    LogBCF_InChiKey_neighbor(i,:)=train.BCF.InChiKey(pred.neighbors(i,:));
                    LogBCF_DTXSID_neighbor(i,:)=train.BCF.DTXSID(pred.neighbors(i,:));
                    LogBCF_DSSTOXMPID_neighbor(i,:)=train.BCF.DSSTOXMPID(pred.neighbors(i,:));
                    LogBCF_Exp_neighbor(i,:)=train.BCF.model.set.y(pred.neighbors(i,:));
                    LogBCF_pred_neighbor(i,:)=train.BCF.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogBCF_Exp_neighbor(i,:),res.LogBCF_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    %res.Conf_index2(i,1)=(res.Conf_index(i)*res.AD_index(i))^0.5;
                    
                    res.AD_index_BCF(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_BCF(i,1)=((1/(1+sqrt(((LogBCF_Exp_neighbor(i,:)-LogBCF_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_BCF(i,1))/2;
                    if isnan(res.AD_index_BCF(i))
                         res.LogBCF_pred(i,1)=NaN;
                        res.AD_BCF(i)=0;
                        res.AD_index_BCF(i)=0;
                        res.Conf_index_BCF(i,1)=0;    
                    end


                    if neighbors==1
                        res.LogBCF_CAS_neighbor(i,:)=LogBCF_CAS_neighbor(i,:);
                        res.LogBCF_InChiKey_neighbor(i,:)=LogBCF_InChiKey_neighbor(i,:);
                        res.LogBCF_DTXSID_neighbor(i,:)=LogBCF_DTXSID_neighbor(i,:);
                        res.LogBCF_DSSTOXMPID_neighbor(i,:)=LogBCF_DSSTOXMPID_neighbor(i,:);
                        res.LogBCF_Exp_neighbor(i,:)=LogBCF_Exp_neighbor(i,:);
                        res.LogBCF_pred_neighbor(i,:)=LogBCF_pred_neighbor(i,:);
                    end                                       
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogBCF experimental= %.3f\n', res.LogBCF_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogBCF predicted= %.3f\n', res.LogBCF_pred(i));
                        if res.AD_BCF(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_BCF(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_BCF(i));
                        %CAS=strjoin(res.LogBCF_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BCF.model.set.K, res.LogBCF_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BCF.model.set.K, res.LogBCF_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BCF.model.set.K, res.LogBCF_pred_neighbor(i,1:5));
                        end
                                                
                    elseif strcmpi(ext,'.txt') && sep==0                       
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogBCF experimental= %.3f\n', res.LogBCF_exp(i));
                        end
                        fprintf(output,'LogBCF predicted= %.3f\n', res.LogBCF_pred(i));
                        if res.AD_BCF(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_BCF(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_BCF(i));
                        %CAS=strjoin(res.LogBCF_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BCF.model.set.K, res.LogBCF_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BCF.model.set.K, res.LogBCF_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BCF.model.set.K, res.LogBCF_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.BCF=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
            end
            
            %Predict Biodegradability values
            %case {'biohc','biohl','biodeg','biodeghl'}
            [Lia,Locb] =ismember({'biohc','biohl','biodeg','biodeghl'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting Biodeg. half-life (Log10 days)';
                Desc=train.Biodeg.Desc;

                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting Biodegradability in LogHalfLife... \n\n			============================================================== \n\n');
                end
                
                Xtest=Xin(:,train.Biodeg.Desc_i);
                
                pred = nnrpred(Xtest,train.Biodeg.model.set.train,train.Biodeg.model.set.y,train.Biodeg.model.set.K,train.Biodeg.model.set.dist_type,train.Biodeg.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.BioDeg_exp=NaN(size(Xtest,1),1);
                end
                res.BioDeg_LogHalfLife_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.Biodeg.model.set.train,Xtest,'auto');
                res.AD_BioDeg=abs(AD.inorout-1)';
                res.AD_BioDeg(round(pred.dc(:,1),3)==0)=1;

                res.AD_index_BioDeg=zeros(size(Xtest,1),1);
                res.Conf_index_BioDeg=zeros(size(Xtest,1),1);
                
                BioDeg_CAS_neighbor=cell(size(Xtest,1),5);
                BioDeg_InChiKey_neighbor=cell(size(Xtest,1),5);
                BioDeg_DTXSID_neighbor=cell(size(Xtest,1),5);
                BioDeg_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                BioDeg_LogHalfLife_Exp_neighbor=zeros(size(Xtest,1),5);
                BioDeg_LogHalfLife_pred_neighbor=zeros(size(Xtest,1),5);
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.Biodeg.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.Biodeg.DTXSID);
                        end
                        if Li
                            if Lo>size(train.Biodeg.DTXSID,1)
                                Lo=mod(Lo,size(train.Biodeg.DTXSID,1));
                            end
                            res.BioDeg_exp(i)=train.Biodeg.model.set.y(Lo);
                        end
                    end
                    BioDeg_CAS_neighbor(i,:)=train.Biodeg.CAS(pred.neighbors(i,:));
                    BioDeg_InChiKey_neighbor(i,:)=train.Biodeg.InChiKey(pred.neighbors(i,:));
                    BioDeg_DTXSID_neighbor(i,:)=train.Biodeg.DTXSID(pred.neighbors(i,:));
                    BioDeg_DSSTOXMPID_neighbor(i,:)=train.Biodeg.DSSTOXMPID(pred.neighbors(i,:));
                    BioDeg_LogHalfLife_Exp_neighbor(i,:)=train.Biodeg.model.set.y(pred.neighbors(i,:));
                    BioDeg_LogHalfLife_pred_neighbor(i,:)=train.Biodeg.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.BioDeg_LogHalfLife_Exp_neighbor(i,:),res.BioDeg_LogHalfLife_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_BioDeg(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_BioDeg(i,1)=((1/(1+sqrt(((BioDeg_LogHalfLife_Exp_neighbor(i,:)-BioDeg_LogHalfLife_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_BioDeg(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.BioDeg_LogHalfLife_pred(i,1)=NaN;
                        res.AD_BioDeg(i)=0;
                        res.AD_index_BioDeg(i)=0;
                        res.Conf_index_BioDeg(i,1)=0;
                    end

                    if neighbors==1
                        res.BioDeg_CAS_neighbor(i,:)=BioDeg_CAS_neighbor(i,:);
                        res.BioDeg_InChiKey_neighbor(i,:)=BioDeg_InChiKey_neighbor(i,:);
                        res.BioDeg_DTXSID_neighbor(i,:)=BioDeg_DTXSID_neighbor(i,:);
                        res.BioDeg_DSSTOXMPID_neighbor(i,:)=BioDeg_DSSTOXMPID_neighbor(i,:);
                        res.BioDeg_LogHalfLife_Exp_neighbor(i,:)=BioDeg_LogHalfLife_Exp_neighbor(i,:);
                        res.BioDeg_LogHalfLife_pred_neighbor(i,:)=BioDeg_LogHalfLife_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'BioDeg_LogHalfLife experimental= %.3f\n', res.BioDeg_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'BioDeg_LogHalfLife predicted= %.3f\n', res.BioDeg_LogHalfLife_pred(i));
                        if res.AD_BioDeg(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_BioDeg(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_BioDeg(i));
                        %CAS=strjoin(res.BioDeg_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.Biodeg.model.set.K, res.BioDeg_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.Biodeg.model.set.K, res.BioDeg_LogHalfLife_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.Biodeg.model.set.K, res.BioDeg_LogHalfLife_pred_neighbor(i,1:5));
                        end
                                               
                    elseif strcmpi(ext,'.txt') && sep==0                        
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'BioDeg_LogHalfLife experimental= %.3f\n', res.BioDeg_exp(i));
                        end
                        fprintf(output,'BioDeg_LogHalfLife predicted= %.3f\n', res.BioDeg_LogHalfLife_pred(i));
                        if res.AD_BioDeg(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_BioDeg(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_BioDeg(i));
                        %CAS=strjoin(res.BioDeg_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.Biodeg.model.set.K, res.BioDeg_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.Biodeg.model.set.K, res.BioDeg_LogHalfLife_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.Biodeg.model.set.K, res.BioDeg_LogHalfLife_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.BioDeg=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
            end
            %Predict RBiodeg values
            %case {'biowin','rb','readybiodeg','rbiodeg'}
            [Lia,Locb] =ismember({'biowin','rb','readybiodeg','rbiodeg'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting Ready-Biodegradability (Binary 0/1)';
                
                Desc=train.RBioDeg.Desc;

                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting Ready Biodegradability... \n\n			============================================================== \n\n');
                end

                Xtest=Xin(:,train.RBioDeg.Desc_i);
                
                pred = knnpred(Xtest,train.RBioDeg.model.set.train,train.RBioDeg.model.set.class,train.RBioDeg.model.set.K,train.RBioDeg.model.set.dist_type,train.RBioDeg.model.set.param.pret_type);
                pred.D=diag(pred.D);
                %pred.w = (ones(1,train.RBioDeg.model.set.K)./train.RBioDeg.model.set.K)';
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.ReadyBiodeg_exp=NaN(size(Xtest,1),1);
                end
                res.ReadyBiodeg_pred(:,1)=pred.class_pred-1;
                AD=classical_leverage(train.RBioDeg.model.set.train,Xtest,'auto');
                res.AD_ReadyBiodeg=abs(AD.inorout-1)';
                res.AD_ReadyBiodeg(round(pred.dc(:,1),3)==0)=1;
                %
                
                %res.dc=pred.dc;
                res.AD_index_ReadyBiodeg=1./(1+nanmedian(pred.dc,2));
                res.AD_index_ReadyBiodeg(isnan(res.AD_index_ReadyBiodeg))=0;

                res.Conf_index_ReadyBiodeg=zeros(size(Xtest,1),1);
                
                ReadyBiodeg_CAS_neighbor=cell(size(Xtest,1),5);
                ReadyBiodeg_InChiKey_neighbor=cell(size(Xtest,1),5);
                ReadyBiodeg_DTXSID_neighbor=cell(size(Xtest,1),5);
                ReadyBiodeg_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                ReadyBiodeg_Exp_neighbor=zeros(size(Xtest,1),5);
                ReadyBiodeg_pred_neighbor=zeros(size(Xtest,1),5);
                                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.RBioDeg.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.RBioDeg.DTXSID);
                        end
                        if Li
                            if Lo>size(train.RBioDeg.DTXSID,1)
                                Lo=mod(Lo,size(train.RBioDeg.DTXSID,1));
                            end
                            res.ReadyBiodeg_exp(i)=train.RBioDeg.model.set.class(Lo);
                        end
                    end
                    ReadyBiodeg_CAS_neighbor(i,:)=train.RBioDeg.CAS(pred.neighbors(i,:));
                    ReadyBiodeg_InChiKey_neighbor(i,:)=train.RBioDeg.InChiKey(pred.neighbors(i,:));
                    ReadyBiodeg_DTXSID_neighbor(i,:)=train.RBioDeg.DTXSID(pred.neighbors(i,:));
                    ReadyBiodeg_DSSTOXMPID_neighbor(i,:)=train.RBioDeg.DSSTOXMPID(pred.neighbors(i,:));
                    ReadyBiodeg_Exp_neighbor(i,:)=train.RBioDeg.model.set.class(pred.neighbors(i,:))-1;
                    ReadyBiodeg_pred_neighbor(i,:)=train.RBioDeg.model.class_calc(pred.neighbors(i,:))-1;
                    
                    rmse=calc_reg_param(ReadyBiodeg_Exp_neighbor(i,:),ReadyBiodeg_pred_neighbor(i,:));
                    res.Conf_index_ReadyBiodeg(i,1)=((1/(1+rmse.RMSEC))+res.AD_index_ReadyBiodeg(i))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.ReadyBiodeg_pred(i,1)=NaN;
                        res.AD_ReadyBiodeg(i)=0;
                        res.AD_index_ReadyBiodeg(i)=0;
                        res.Conf_index_ReadyBiodeg(i,1)=0;
                    end

                    %                res.Conf_index(i,1)=1/(1+sqrt(((res.ReadyBiodeg_Exp_neighbor(i,:)-res.ReadyBiodeg_pred_neighbor(i,:)).^2)*pred.w(i,:)'));
                    if neighbors==1
                        res.ReadyBiodeg_CAS_neighbor(i,:)=ReadyBiodeg_CAS_neighbor(i,:);
                        res.ReadyBiodeg_InChiKey_neighbor(i,:)=ReadyBiodeg_InChiKey_neighbor(i,:);
                        res.ReadyBiodeg_DTXSID_neighbor(i,:)=ReadyBiodeg_DTXSID_neighbor(i,:);
                        res.ReadyBiodeg_DSSTOXMPID_neighbor(i,:)=ReadyBiodeg_DSSTOXMPID_neighbor(i,:);
                        res.ReadyBiodeg_Exp_neighbor(i,:)=ReadyBiodeg_Exp_neighbor(i,:);
                        res.ReadyBiodeg_pred_neighbor(i,:)=ReadyBiodeg_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'ReadyBiodeg experimental= %.3f\n', res.ReadyBiodeg_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'ReadyBiodeg predicted= %d\n', res.ReadyBiodeg_pred(i));
                        if res.AD_ReadyBiodeg(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_ReadyBiodeg(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_ReadyBiodeg(i));
                        %CAS=strjoin(res.ReadyBiodeg_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.RBioDeg.model.set.K, res.ReadyBiodeg_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15d,%15d,%15d,%15d,%15d\n',train.RBioDeg.model.set.K, res.ReadyBiodeg_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14d,%15d,%15d,%15d,%15d\n\n',train.RBioDeg.model.set.K, res.ReadyBiodeg_pred_neighbor(i,1:5));
                        end                        
                        
                    elseif strcmpi(ext,'.txt') && sep==0                        
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'ReadyBiodeg experimental= %.3f\n', res.ReadyBiodeg_exp(i));
                        end
                        fprintf(output,'ReadyBiodeg predicted= %d\n', res.ReadyBiodeg_pred(i));
                        if res.AD_ReadyBiodeg(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_ReadyBiodeg(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_ReadyBiodeg(i));
                        %CAS=strjoin(res.ReadyBiodeg_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.RBioDeg.model.set.K, res.ReadyBiodeg_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15d,%15d,%15d,%15d,%15d\n',train.RBioDeg.model.set.K, res.ReadyBiodeg_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14d,%15d,%15d,%15d,%15d\n\n',train.RBioDeg.model.set.K, res.ReadyBiodeg_pred_neighbor(i,1:5));
                        end
                        
                    end
                end                
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.RBioDeg=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
                
            end
            %Predict KM values
            %case {'km','logkm'}
            [Lia,Locb] =ismember({'km','logkm'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting LogKm half-life values (Log10 days)';
                Desc=train.KM.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogKmHL values... \n\n			==============================================================  \n\n');
                end
                
                Xtest=Xin(:,train.KM.Desc_i);
                
                pred = nnrpred(Xtest,train.KM.model.set.train,train.KM.model.set.y,train.KM.model.set.K,train.KM.model.set.dist_type,train.KM.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogKM_exp=NaN(size(Xtest,1),1);
                end
                res.LogKM_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.KM.model.set.train,Xtest,'auto');
                res.AD_KM=abs(AD.inorout-1)';
                res.AD_KM(round(pred.dc(:,1),3)==0)=1;

                res.AD_index_KM=zeros(size(Xtest,1),1);
                res.Conf_index_KM=zeros(size(Xtest,1),1);
                
                KM_CAS_neighbor=cell(size(Xtest,1),5);
                KM_InChiKey_neighbor=cell(size(Xtest,1),5);
                KM_DTXSID_neighbor=cell(size(Xtest,1),5);
                KM_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogKM_Exp_neighbor=zeros(size(Xtest,1),5);
                LogKM_pred_neighbor=zeros(size(Xtest,1),5);
                                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.KM.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.KM.DTXSID);
                        end
                        if Li
                            if Lo>size(train.KM.DTXSID,1)
                                Lo=mod(Lo,size(train.KM.DTXSID,1));
                            end
                            res.LogKM_exp(i)=train.KM.model.set.y(Lo);
                        end
                    end
                    KM_CAS_neighbor(i,:)=train.KM.CAS(pred.neighbors(i,:));
                    KM_InChiKey_neighbor(i,:)=train.KM.InChiKey(pred.neighbors(i,:));
                    KM_DTXSID_neighbor(i,:)=train.KM.DTXSID(pred.neighbors(i,:));
                    KM_DSSTOXMPID_neighbor(i,:)=train.KM.DSSTOXMPID(pred.neighbors(i,:));
                    LogKM_Exp_neighbor(i,:)=train.KM.model.set.y(pred.neighbors(i,:));
                    LogKM_pred_neighbor(i,:)=train.KM.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogKM_Exp_neighbor(i,:),res.LogKM_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_KM(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_KM(i,1)=((1/(1+sqrt(((LogKM_Exp_neighbor(i,:)-LogKM_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_KM(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.LogKM_pred(i,1)=NaN;
                        res.AD_KM(i)=0;
                        res.AD_index_KM(i)=0;
                        res.Conf_index_KM(i,1)=0;
                    end

                    if neighbors==1
                        res.KM_CAS_neighbor(i,:)=KM_CAS_neighbor(i,:);
                        res.KM_InChiKey_neighbor(i,:)=KM_InChiKey_neighbor(i,:);
                        res.KM_DTXSID_neighbor(i,:)=KM_DTXSID_neighbor(i,:);
                        res.KM_DSSTOXMPID_neighbor(i,:)=KM_DSSTOXMPID_neighbor(i,:);
                        res.LogKM_Exp_neighbor(i,:)=LogKM_Exp_neighbor(i,:);
                        res.LogKM_pred_neighbor(i,:)=LogKM_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogKM experimental= %.3f\n', res.LogKM_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogKmHL predicted= %.3f\n', res.LogKM_pred(i));
                        if res.AD_KM(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_KM(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_KM(i));
                        %CAS=strjoin(res.KM_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KM.model.set.K, res.KM_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KM.model.set.K, res.LogKM_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KM.model.set.K, res.LogKM_pred_neighbor(i,1:5));
                        end
                        
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogKM experimental= %.3f\n', res.LogKM_exp(i));
                        end
                        fprintf(output,'LogKmHL predicted= %.3f\n', res.LogKM_pred(i));
                        if res.AD_KM(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_KM(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_KM(i));
                        %CAS=strjoin(res.KM_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KM.model.set.K, res.KM_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KM.model.set.K, res.LogKM_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KM.model.set.K, res.LogKM_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.KM=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
            end
            
            %Predict KOC values
            %case {'logkoc','koc'}
            [Lia,Locb] =ismember({'koc','logkoc'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting LogKoc values (Log10 L/Kg)';
                
                Desc=train.KOC.Desc;

                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogKoc values... \n\n			==============================================================  \n\n');
                end
                
                Xtest=Xin(:,train.KOC.Desc_i);
                
                pred = nnrpred(Xtest,train.KOC.model.set.train,train.KOC.model.set.y,train.KOC.model.set.K,train.KOC.model.set.dist_type,train.KOC.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogKoc_exp=NaN(size(Xtest,1),1);
                end
                res.LogKoc_pred(:,1)=pred.y_pred_weighted;
                AD=classical_leverage(train.KOC.model.set.train,Xtest,'auto');
                res.AD_LogKoc=abs(AD.inorout-1)';
                res.AD_LogKoc(round(pred.dc(:,1),3)==0)=1;

                res.AD_index_LogKoc=zeros(size(Xtest,1),1);
                res.Conf_index_LogKoc=zeros(size(Xtest,1),1);
                
                Koc_CAS_neighbor=cell(size(Xtest,1),5);
                Koc_InChiKey_neighbor=cell(size(Xtest,1),5);
                Koc_DTXSID_neighbor=cell(size(Xtest,1),5);
                Koc_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                LogKoc_Exp_neighbor=zeros(size(Xtest,1),5);
                LogKoc_pred_neighbor=zeros(size(Xtest,1),5);
                
                
                for i=1:size(Xtest(:,1))
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.KOC.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.KOC.DTXSID);
                        end
                        if Li
                            if Lo>size(train.KOC.DTXSID,1)
                                Lo=mod(Lo,size(train.KOC.DTXSID,1));
                            end
                            res.LogKoc_exp(i)=train.KOC.model.set.y(Lo);
                        end
                    end
                    Koc_CAS_neighbor(i,:)=train.KOC.CAS(pred.neighbors(i,:));
                    Koc_InChiKey_neighbor(i,:)=train.KOC.InChiKey(pred.neighbors(i,:));
                    Koc_DTXSID_neighbor(i,:)=train.KOC.DTXSID(pred.neighbors(i,:));
                    Koc_DSSTOXMPID_neighbor(i,:)=train.KOC.DSSTOXMPID(pred.neighbors(i,:));
                    LogKoc_Exp_neighbor(i,:)=train.KOC.model.set.y(pred.neighbors(i,:));
                    LogKoc_pred_neighbor(i,:)=train.KOC.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.LogKoc_Exp_neighbor(i,:),res.LogKoc_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_LogKoc(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    res.Conf_index_LogKoc(i,1)=((1/(1+sqrt(((LogKoc_Exp_neighbor(i,:)-LogKoc_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_LogKoc(i,1))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1))
                        res.LogKoc_pred(i,1)=NaN;
                        res.AD_LogKoc(i)=0;
                        res.AD_index_LogKoc(i)=0;
                        res.Conf_index_LogKoc(i,1)=0;    
                    end
                    
                    
                    if neighbors==1
                        res.Koc_CAS_neighbor(i,:)=Koc_CAS_neighbor(i,:);
                        res.Koc_InChiKey_neighbor(i,:)=Koc_InChiKey_neighbor(i,:);
                        res.Koc_DTXSID_neighbor(i,:)=Koc_DTXSID_neighbor(i,:);
                        res.Koc_DSSTOXMPID_neighbor(i,:)=Koc_DSSTOXMPID_neighbor(i,:);
                        res.LogKoc_Exp_neighbor(i,:)=LogKoc_Exp_neighbor(i,:);
                        res.LogKoc_pred_neighbor(i,:)=LogKoc_pred_neighbor(i,:);
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogKOC experimental= %.3f\n', res.LogKoc_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogKOC predicted= %.3f\n', res.LogKoc_pred(i));
                        if res.AD_LogKoc(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_LogKoc(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_LogKoc(i));
                        %CAS=strjoin(res.Koc_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KOC.model.set.K, res.Koc_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KOC.model.set.K, res.LogKoc_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KOC.model.set.K, res.LogKoc_pred_neighbor(i,1:5));
                        end
                                                
                    elseif strcmpi(ext,'.txt') && sep==0
                                               
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogKOC experimental= %.3f\n', res.LogKoc_exp(i));
                        end
                        fprintf(output,'LogKOC predicted= %.3f\n', res.LogKoc_pred(i));
                        if res.AD_LogKoc(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_LogKoc(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_LogKoc(i));
                        %CAS=strjoin(res.Koc_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KOC.model.set.K, res.Koc_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KOC.model.set.K, res.LogKoc_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KOC.model.set.K, res.LogKoc_pred_neighbor(i,1:5));
                        end
                        
                    end
                end                
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.KOC=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                %end clean memory
            end
            
            
%------------- ADME Properties -----------------------%
    
   %Predict FUB values
            %case {'fub','fu'}
            [Lia,Locb] =ismember({'fu','fub'},lower(prop));
     if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user', 'Error','error');
                    return;
                end
                d.Message='Predicting FUB values (fraction)';
                Desc=train.FUB.Desc;
                        
            
            if strcmpi(ext,'.txt') && sep==0
                fprintf(output,'\n\n\t\t\t\t\t Predicting FUB values... \n\n			==============================================================  \n\n');
            end

            XinCDK_FUB=XinCDK(:,train.FUB.cdk_in);
            Xtest=[Xin(:,train.PadelVarIn(train.FUB.Padel_in)), XinCDK_FUB];           

            Xtest=Xtest(:,train.FUB.Desc_i);
            
            pred = nnrpred(Xtest,train.FUB.model.set.train,train.FUB.model.set.y,train.FUB.model.set.K,train.FUB.model.set.dist_type,train.FUB.model.set.param.pret_type);
            pred.D=diag(pred.D);
            %pred.D=[];
            res.MoleculeID=MoleculeNames;
            if exp
                res.FUB_exp=NaN(size(Xtest,1),1);
            end
            res.FUB_pred(:,1)=pred.y_pred_weighted;
            AD=classical_leverage(train.FUB.model.set.train,Xtest,'auto');
            res.AD_FUB=abs(AD.inorout-1)';
            res.AD_FUB(round(pred.dc(:,1),3)==0)=1;
            
            res.AD_index_FUB=zeros(size(Xtest,1),1);
            res.Conf_index_FUB=zeros(size(Xtest,1),1);
            
            FUB_CAS_neighbor=cell(size(Xtest,1),5);
            %FUB_InChiKey_neighbor=cell(size(Xtest,1),5);
            FUB_DTXSID_neighbor=cell(size(Xtest,1),5);
            %FUB_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
            FUB_Exp_neighbor=zeros(size(Xtest,1),5);
            FUB_pred_neighbor=zeros(size(Xtest,1),5);            
            
            for i=1:size(Xtest(:,1))
                Li=0;
                if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                    if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                        [Li,Lo] = ismember(MoleculeNames(i),train.FUB.CAS);
                    elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                        [Li,Lo] = ismember(MoleculeNames{i},train.FUB.DTXSID);
                    end
                    if Li
                        res.FUB_exp(i)=train.FUB.model.set.y(Lo);
                    end
                end
                FUB_CAS_neighbor(i,:)=train.FUB.CAS(pred.neighbors(i,:));
                %FUB_InChiKey_neighbor(i,:)=train.FUB.InChiKey(pred.neighbors(i,:));
                FUB_DTXSID_neighbor(i,:)=train.FUB.DTXSID(pred.neighbors(i,:));
                %FUB_DSSTOXMPID_neighbor(i,:)=train.FUB.DSSTOXMPID(pred.neighbors(i,:));
                FUB_Exp_neighbor(i,:)=train.FUB.model.set.y(pred.neighbors(i,:));
                FUB_pred_neighbor(i,:)=train.FUB.model.yc_weighted(pred.neighbors(i,:));
                
%                 rmse=calc_reg_param(res.FUB_Exp_neighbor(i,:),res.FUB_pred_neighbor(i,:));
%                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);

                res.AD_index_FUB(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                res.Conf_index_FUB(i,1)=((1/(1+sqrt(((FUB_Exp_neighbor(i,:)-FUB_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_FUB(i,1))/2;
                if isempty(find(~isnan(pred.dc(i,:)), 1))
                    res.FUB_pred(i,1)=NaN;
                    res.AD_FUB(i)=0;
                    res.AD_index_FUB(i)=0;
                    res.Conf_index_FUB(i,1)=0;    
                end

                if neighbors==1
                    res.FUB_CAS_neighbor(i,:)=FUB_CAS_neighbor(i,:);
                    %res.FUB_InChiKey_neighbor(i,:)=FUB_InChiKey_neighbor(i,:);
                    res.FUB_DTXSID_neighbor(i,:)=FUB_DTXSID_neighbor(i,:);
                    %res.FUB_DSSTOXMPID_neighbor(i,:)=FUB_DSSTOXMPID_neighbor(i,:);
                    res.FUB_Exp_neighbor(i,:)=FUB_Exp_neighbor(i,:);
                    res.FUB_pred_neighbor(i,:)=FUB_pred_neighbor(i,:);
                end
                
                if strcmpi(ext,'.txt') && sep==1
                    %res.Xtest=Xtest;
                    fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                    if exp
                        fprintf(output(Locb(find(Locb))),'FUB experimental= %.3f\n', res.FUB_exp(i));
                    end
                    fprintf(output(Locb(find(Locb))),'FUB predicted= %.3f\n', res.FUB_pred(i));
                    if res.AD_FUB(i)==1
                        fprintf(output(Locb(find(Locb))),'AD: inside\n');
                    else
                        fprintf(output(Locb(find(Locb))),'AD: outside\n');
                    end
                    fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_FUB(i));
                    fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_FUB(i));
                    %CAS=strjoin(res.FUB_CAS_neighbor(i,1:5),',\t');
                    %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                    if neighbors==1
                        fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.FUB.model.set.K, res.FUB_CAS_neighbor{i,1:5});
                        fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.FUB.model.set.K, res.FUB_Exp_neighbor(i,1:5));
                        fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.FUB.model.set.K, res.FUB_pred_neighbor(i,1:5));
                    end
                    
                elseif strcmpi(ext,'.txt') && sep==0                    
                    
                    %res.Xtest=Xtest;
                    fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                    if exp
                        fprintf(output,'FUB experimental= %.3f\n', res.FUB_exp(i));
                    end
                    fprintf(output,'FUB predicted= %.3f\n', res.FUB_pred(i));
                    if res.AD_FUB(i)==1
                        fprintf(output,'AD: inside\n');
                    else
                        fprintf(output,'AD: outside\n');
                    end
                    fprintf(output,'AD_index= %.2f\n', res.AD_index_FUB(i));
                    fprintf(output,'Conf_index= %.2f\n', res.Conf_index_FUB(i));
                    %CAS=strjoin(res.FUB_CAS_neighbor(i,1:5),',\t');
                    %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                    if neighbors==1
                        fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.FUB.model.set.K, res.FUB_CAS_neighbor{i,1:5});
                        fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.FUB.model.set.K, res.FUB_Exp_neighbor(i,1:5));
                        fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.FUB.model.set.K, res.FUB_pred_neighbor(i,1:5));
                    end

                end
            end
                        
            if sep==1 && strcmpi(ext,'.csv')
                T=struct2table(res);
                if printtDesc==1
                    Xtest=array2table(Xtest,'VariableNames',Desc);
                    
                    T=[T Xtest];
                    res.Descriptors=Xtest;
                end
                writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                fclose(output(Locb(find(Locb))));
                clear('T');
                
            elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                                
                Xtest(:,ismember(Desc,DescNames))=[];
            
                Desc(ismember(Desc,DescNames))=[];
                
                DescNames=[DescNames Desc];
                
                DescMat=[DescMat Xtest];
            end
            
            if sep==1
              resf.FUB=res;
              clear('res');
            end
            % Clean memory
            clear('XinCDK_FUB');
            clear('Xtest');
            clear('pred');         
            clear('AD');
            %end clean memory            
    end 
    
    %Predict Clint values
            %case {'clint','cl'}
            [Lia,Locb] =ismember({'cl','clint'},lower(prop));
    if find(Lia)
        step=step+1;
        d.Value=step/steps;
        if d.CancelRequested
            msgbox('Canceled by user', 'Error','error');
            return;
        end
        d.Message='Predicting Clint values';
        Desc=train.Clint.Desc;            
            
            if strcmpi(ext,'.txt') && sep==0
                fprintf(output,'\n\n\t\t\t\t\t Predicting Clint values... \n\n			==============================================================  \n\n');
            end
            
            XinCDK_Clint=XinCDK(:,train.Clint.cdk_in);
            Xtest=[Xin(:,train.PadelVarIn(train.Clint.Padel_in)), XinCDK_Clint];
            
            Xtest=Xtest(:,train.Clint.Desc_i);
            
            pred = nnrpred(Xtest,train.Clint.model.set.train,train.Clint.model.set.y,train.Clint.model.set.K,train.Clint.model.set.dist_type,train.Clint.model.set.param.pret_type);
            pred.D=diag(pred.D);
            %pred.D=[];
            res.MoleculeID=MoleculeNames;
            if exp
                res.Clint_exp=NaN(size(Xtest,1),1);
            end
            res.Clint_pred(:,1)=pred.y_pred_weighted;
            AD=classical_leverage(train.Clint.model.set.train,Xtest,'auto');
            res.AD_Clint=abs(AD.inorout-1)';
            res.AD_Clint(round(pred.dc(:,1),3)==0)=1;
            
            res.AD_index_Clint=zeros(size(Xtest,1),1);
            res.Conf_index_Clint=zeros(size(Xtest,1),1);
            
            Clint_CAS_neighbor=cell(size(Xtest,1),5);
            Clint_InChiKey_neighbor=cell(size(Xtest,1),5);
            Clint_DTXSID_neighbor=cell(size(Xtest,1),5);
            %Clint_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
            Clint_Exp_neighbor=zeros(size(Xtest,1),5);
            Clint_pred_neighbor=zeros(size(Xtest,1),5);            
            
            for i=1:size(Xtest(:,1))
                Li=0;
                if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                    if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                        [Li,Lo] = ismember(MoleculeNames(i),train.Clint.CAS);
                    elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                        [Li,Lo] = ismember(MoleculeNames{i},train.Clint.DTXSID);
                    end
                    if Li
                        res.Clint_exp(i)=train.Clint.model.set.y(Lo);
                    end
                end
                Clint_CAS_neighbor(i,:)=train.Clint.CAS(pred.neighbors(i,:));
                Clint_InChiKey_neighbor(i,:)=train.Clint.InChiKey(pred.neighbors(i,:));
                Clint_DTXSID_neighbor(i,:)=train.Clint.DTXSID(pred.neighbors(i,:));
                %Clint_DSSTOXMPID_neighbor(i,:)=train.Clint.DSSTOXMPID(pred.neighbors(i,:));
                Clint_Exp_neighbor(i,:)=train.Clint.model.set.y(pred.neighbors(i,:));
                Clint_pred_neighbor(i,:)=train.Clint.model.yc_weighted(pred.neighbors(i,:));
                
%                 rmse=calc_reg_param(res.Clint_Exp_neighbor(i,:),res.Clint_pred_neighbor(i,:));
%                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);

                res.AD_index_Clint(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                res.Conf_index_Clint(i,1)=((1/(1+sqrt(((Clint_Exp_neighbor(i,:)-Clint_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_Clint(i,1))/2;
                if isempty(find(~isnan(pred.dc(i,:)), 1))
                    res.Clint_pred(i,1)=NaN;
                    res.AD_Clint(i)=0;
                    res.AD_index_Clint(i)=0;
                    res.Conf_index_Clint(i,1)=0;    
                end
                
                
                if neighbors==1
                    res.Clint_CAS_neighbor(i,:)=Clint_CAS_neighbor(i,:);
                    res.Clint_InChiKey_neighbor(i,:)=Clint_InChiKey_neighbor(i,:);
                    res.Clint_DTXSID_neighbor(i,:)=Clint_DTXSID_neighbor(i,:);
                    %res.Clint_DSSTOXMPID_neighbor(i,:)=Clint_DSSTOXMPID_neighbor(i,:);
                    res.Clint_Exp_neighbor(i,:)=Clint_Exp_neighbor(i,:);
                    res.Clint_pred_neighbor(i,:)=Clint_pred_neighbor(i,:);
                end
                

                if strcmpi(ext,'.txt') && sep==1
                    %res.Xtest=Xtest;
                    fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                    if exp
                        fprintf(output(Locb(find(Locb))),'Clint experimental= %.3f\n', res.Clint_exp(i));
                    end
                    fprintf(output(Locb(find(Locb))),'Clint predicted= %.3f\n', res.Clint_pred(i));
                    if res.AD_Clint(i)==1
                        fprintf(output(Locb(find(Locb))),'AD: inside\n');
                    else
                        fprintf(output(Locb(find(Locb))),'AD: outside\n');
                    end
                    fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_Clint(i));
                    fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_Clint(i));
                    %CAS=strjoin(res.Clint_CAS_neighbor(i,1:5),',\t');
                    %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                    if neighbors==1
                        fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.Clint.model.set.K, res.Clint_CAS_neighbor{i,1:5});
                        fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.Clint.model.set.K, res.Clint_Exp_neighbor(i,1:5));
                        fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.Clint.model.set.K, res.Clint_pred_neighbor(i,1:5));
                    end
                    
                elseif strcmpi(ext,'.txt') && sep==0                    
                    
                    %res.Xtest=Xtest;
                    fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                    if exp
                        fprintf(output,'Clint experimental= %.3f\n', res.Clint_exp(i));
                    end
                    fprintf(output,'Clint predicted= %.3f\n', res.Clint_pred(i));
                    if res.AD_Clint(i)==1
                        fprintf(output,'AD: inside\n');
                    else
                        fprintf(output,'AD: outside\n');
                    end
                    fprintf(output,'AD_index= %.2f\n', res.AD_index_Clint(i));
                    fprintf(output,'Conf_index= %.2f\n', res.Conf_index_Clint(i));
                    %CAS=strjoin(res.Clint_CAS_neighbor(i,1:5),',\t');
                    %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                    if neighbors==1
                        fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.Clint.model.set.K, res.Clint_CAS_neighbor{i,1:5});
                        fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.Clint.model.set.K, res.Clint_Exp_neighbor(i,1:5));
                        fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.Clint.model.set.K, res.Clint_pred_neighbor(i,1:5));
                    end

                end
            end
                        
            if sep==1 && strcmpi(ext,'.csv')
                T=struct2table(res);
                if printtDesc==1
                    Xtest=array2table(Xtest,'VariableNames',Desc);
                    
                    T=[T Xtest];
                    res.Descriptors=Xtest;
                end
                writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                fclose(output(Locb(find(Locb))));
                clear('T');
                
            elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                                
                Xtest(:,ismember(Desc,DescNames))=[];
            
                Desc(ismember(Desc,DescNames))=[];
                
                DescNames=[DescNames Desc];
                
                DescMat=[DescMat Xtest];
            end
            
            if sep==1
              resf.Clint=res;
              clear('res');
            end
            % Clean memory
            clear('XinCDK_Clint');
            clear('Xtest');
            clear('pred');         
            clear('AD');
            %end clean memory            
    end 
            
            % Tox properties
            
%---------- Toxcity Endpoints ----------
           
            
            %Predict CERAPP endpoints
            %case {'CERAPP','ER'}
            [Lia,Locb] =ismember({'cerapp','er'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting Estrogen Receptor Activity (CERAPP)';
                Desc=train.CERAPP.DescIn;

                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting CERAPP endpoints... \n\n			==============================================================  \n\n');
                end
                
                %XlabelsCDK
                XinCDK_CERAPP=XinCDK(:,train.CERAPP.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.CERAPP.Padel_in)), XinCDK_CERAPP];
                
                XtestAG=Xtest(:,train.CERAPP.model_AG.DescAG_i);
                XtestAN=Xtest(:,train.CERAPP.model_AN.DescAN_i);
                XtestBD=Xtest(:,train.CERAPP.model_BD.DescBD_i);
                
                predAG = knnpred2(XtestAG,train.CERAPP.model_AG.set.train,train.CERAPP.model_AG.set.class,train.CERAPP.model_AG.set.K,train.CERAPP.model_AG.set.dist_type,train.CERAPP.model_AG.set.param.pret_type);
                predAG.D=[];
                predAN = knnpred2(XtestAN,train.CERAPP.model_AN.set.train,train.CERAPP.model_AN.set.class,train.CERAPP.model_AN.set.K,train.CERAPP.model_AN.set.dist_type,train.CERAPP.model_AN.set.param.pret_type);
                predAN.D=[];
                predBD = knnpred2(XtestBD,train.CERAPP.model_BD.set.train,train.CERAPP.model_BD.set.class,train.CERAPP.model_BD.set.K,train.CERAPP.model_BD.set.dist_type,train.CERAPP.model_BD.set.param.pret_type);
                predBD.D=[];
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.CERAPP_Ago_exp=cell(size(Xtest,1),1);
                end
                res.CERAPP_Ago_pred(:,1)=predAG.class_pred-1;
                AD=classical_leverage(train.CERAPP.model_AG.set.train,XtestAG,'auto');
                res.AD_CERAPP_Ago=abs(AD.inorout-1)';
                res.AD_index_CERAPP_Ago=1-test_pretreatment(predAG.dc(:,1),train.CERAPP.model_AG.set.dc_param);
                res.AD_index_CERAPP_Ago(find(res.AD_index_CERAPP_Ago<0),1)=1./(1+predAG.dc(find(res.AD_index_CERAPP_Ago<0),1));
                res.AD_CERAPP_Ago(find(isnan(predAG.dc(:,1))))=0;
                res.AD_index_CERAPP_Ago(find(isnan(predAG.dc(:,1))))=0;
                res.AD_CERAPP_Ago(find(res.AD_index_CERAPP_Ago>0.5))=1;
                res.Conf_index_CERAPP_Ago=zeros(size(XtestAG,1),1);
                if exp
                    res.CERAPP_Anta_exp=cell(size(Xtest,1),1);
                end
                res.CERAPP_Anta_pred(:,1)=predAN.class_pred-1;
                AD=classical_leverage(train.CERAPP.model_AN.set.train,XtestAN,'auto');
                res.AD_CERAPP_Anta=abs(AD.inorout-1)';
                res.AD_index_CERAPP_Anta=1-test_pretreatment(predAN.dc(:,1),train.CERAPP.model_AN.set.dc_param);
                res.AD_index_CERAPP_Anta(find(res.AD_index_CERAPP_Anta<0),1)=1./(1+predAN.dc(find(res.AD_index_CERAPP_Anta<0),1));
                res.AD_CERAPP_Anta(find(isnan(predAN.dc(:,1))))=0;
                res.AD_index_CERAPP_Anta(find(isnan(predAN.dc(:,1))))=0;
                res.AD_CERAPP_Anta(find(res.AD_index_CERAPP_Anta>0.5))=1;
                res.Conf_index_CERAPP_Anta=zeros(size(XtestAN,1),1);
                if exp
                    res.CERAPP_Bind_exp=cell(size(Xtest,1),1);
                end
                res.CERAPP_Bind_pred(:,1)=predBD.class_pred-1;
                AD=classical_leverage(train.CERAPP.model_BD.set.train,XtestBD,'auto');
                res.AD_CERAPP_Bind=abs(AD.inorout-1)';
                res.AD_index_CERAPP_Bind=1-test_pretreatment(predBD.dc(:,1),train.CERAPP.model_BD.set.dc_param);
                res.AD_index_CERAPP_Bind(find(res.AD_index_CERAPP_Bind<0),1)=1./(1+predBD.dc(find(res.AD_index_CERAPP_Bind<0),1));
                res.AD_CERAPP_Bind(find(isnan(predBD.dc(:,1))))=0;
                res.AD_index_CERAPP_Bind(find(isnan(predBD.dc(:,1))))=0;
                res.AD_CERAPP_Bind(find(res.AD_index_CERAPP_Bind>0.5))=1;
                res.Conf_index_CERAPP_Bind=zeros(size(XtestBD,1),1);
                
                
                for i=1:size(Xtest(:,1))
                    Li_ag=0;
                    Li_an=0;
                    Li_bd=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li_ag,Lo_ag] = ismember(MoleculeNames(i),train.CERAPP.model_AG.CAS);
                            [Li_an,Lo_an] = ismember(MoleculeNames(i),train.CERAPP.model_AN.CAS);
                            [Li_bd,Lo_bd] = ismember(MoleculeNames(i),train.CERAPP.model_BD.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li_ag,Lo_ag] = ismember(MoleculeNames{i},train.CERAPP.model_AG.DTXSID);
                            [Li_an,Lo_an] = ismember(MoleculeNames{i},train.CERAPP.model_AN.DTXSID);
                            [Li_bd,Lo_bd] = ismember(MoleculeNames{i},train.CERAPP.model_BD.DTXSID);
                        end
                        if Li_ag
                            if Lo_ag>size(train.CERAPP.model_AG.DTXSID,1)
                                Lo_ag=mod(Lo_ag,size(train.CERAPP.model_AG.DTXSID,1));
                            end
                            res.CERAPP_Ago_exp(i)=train.CERAPP.model_AG.set.class_Exp(Lo_ag);
                        end
                        if Li_an
                            if Lo_an>size(train.CERAPP.model_AN.DTXSID,1)
                                Lo_an=mod(Lo_an,size(train.CERAPP.model_AN.DTXSID,1));
                            end
                            res.CERAPP_Anta_exp(i,1)=train.CERAPP.model_AN.set.class_Exp(Lo_an);
                        end
                        if Li_bd
                            if Lo_bd>size(train.CERAPP.model_BD.DTXSID,1)
                                Lo_bd=mod(Lo_bd,size(train.CERAPP.model_BD.DTXSID,1));
                            end
                            res.CERAPP_Bind_exp(i,1)=train.CERAPP.model_BD.set.class_Exp(Lo_bd);
                        end
                    end
                    res.Conf_index_CERAPP_Ago(i,1)=train.CERAPP.model_AG.conc_AG(predAG.neighbors(i,:),1)'*predAG.w(i,:)';
                    res.Conf_index_CERAPP_Anta(i,1)=train.CERAPP.model_AN.conc_AN(predAN.neighbors(i,:),1)'*predAN.w(i,:)';
                    res.Conf_index_CERAPP_Bind(i,1)=train.CERAPP.model_BD.conc_BD(predBD.neighbors(i,:),1)'*predBD.w(i,:)';
                    
                    if neighbors==1
                        res.CERAPP_Ago_CAS_neighbor(i,:)=train.CERAPP.model_AG.CAS(predAG.neighbors(i,:));
                        res.CERAPP_Ago_InChiKey_neighbor(i,:)=train.CERAPP.model_AG.InChiKey(predAG.neighbors(i,:));
                        res.CERAPP_Ago_DTXSID_neighbor(i,:)=train.CERAPP.model_AG.DTXSID(predAG.neighbors(i,:));
                        %res.CERAPP_Ago_DSSTOXMPID_neighbor(i,:)=train.CERAPP.model_AG.DSSTOXMPID(pred.neighbors(i,:));
                        res.CERAPP_Ago_Exp_neighbor(i,:)=train.CERAPP.model_AG.set.class_Exp(predAG.neighbors(i,:));
                        res.CERAPP_Ago_pred_neighbor(i,:)=train.CERAPP.model_AG.set.class_S(predAG.neighbors(i,:));
                        
                        res.CERAPP_Anta_CAS_neighbor(i,:)=train.CERAPP.model_AN.CAS(predAN.neighbors(i,:));
                        res.CERAPP_Anta_InChiKey_neighbor(i,:)=train.CERAPP.model_AN.InChiKey(predAN.neighbors(i,:));
                        res.CERAPP_Anta_DTXSID_neighbor(i,:)=train.CERAPP.model_AN.DTXSID(predAN.neighbors(i,:));
                        %res.CERAPP_Anta_DSSTOXMPID_neighbor(i,:)=train.CERAPP.model_AN.DSSTOXMPID(pred.neighbors(i,:));
                        res.CERAPP_Anta_Exp_neighbor(i,:)=train.CERAPP.model_AN.set.class_Exp(predAN.neighbors(i,:));
                        res.CERAPP_Anta_pred_neighbor(i,:)=train.CERAPP.model_AN.set.class_S(predAN.neighbors(i,:));
                        
                        res.CERAPP_Bind_CAS_neighbor(i,:)=train.CERAPP.model_BD.CAS(predBD.neighbors(i,:));
                        res.CERAPP_Bind_InChiKey_neighbor(i,:)=train.CERAPP.model_BD.InChiKey(predBD.neighbors(i,:));
                        res.CERAPP_Bind_DTXSID_neighbor(i,:)=train.CERAPP.model_BD.DTXSID(predBD.neighbors(i,:));
                        %res.CERAPP_Bind_DSSTOXMPID_neighbor(i,:)=train.CERAPP.model_BD.DSSTOXMPID(predBD.neighbors(i,:));
                        res.CERAPP_Bind_Exp_neighbor(i,:)=train.CERAPP.model_BD.set.class_Exp(predBD.neighbors(i,:));
                        res.CERAPP_Bind_pred_neighbor(i,:)=train.CERAPP.model_BD.set.class_S(predBD.neighbors(i,:));
                        
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'AG experimental= %s, AN experimental= %s, BD experimental= %s\n', res.CERAPP_Ago_exp{i},res.CERAPP_Anta_exp{i},res.CERAPP_Bind_exp{i});
                        end
                        fprintf(output(Locb(find(Locb))),'AG predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Ago_pred(i),res.AD_CERAPP_Ago(i),res.AD_index_CERAPP_Ago(i),res.Conf_index_CERAPP_Ago(i));
                        fprintf(output(Locb(find(Locb))),'AN predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Anta_pred(i),res.AD_CERAPP_Anta(i),res.AD_index_CERAPP_Anta(i),res.Conf_index_CERAPP_Anta(i));
                        fprintf(output(Locb(find(Locb))),'BD category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Bind_pred(i),res.AD_CERAPP_Bind(i),res.AD_index_CERAPP_Bind(i),res.Conf_index_CERAPP_Bind(i));
                        
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_AG.set.K, res.CERAPP_Ago_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_AN.set.K, res.CERAPP_Anta_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_BD.set.K, res.CERAPP_Bind_CAS_neighbor{i,1:5});
                        end
                        
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'AG experimental= %s, AN experimental= %s, BD experimental= %s\n', res.CERAPP_Ago_exp{i},res.CERAPP_Anta_exp{i},res.CERAPP_Bind_exp{i});
                        end
                        fprintf(output,'AG predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Ago_pred(i),res.AD_CERAPP_Ago(i),res.AD_index_CERAPP_Ago(i),res.Conf_index_CERAPP_Ago(i));
                        fprintf(output,'AN predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Anta_pred(i),res.AD_CERAPP_Anta(i),res.AD_index_CERAPP_Anta(i),res.Conf_index_CERAPP_Anta(i));
                        fprintf(output,'BD category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Bind_pred(i),res.AD_CERAPP_Bind(i),res.AD_index_CERAPP_Bind(i),res.Conf_index_CERAPP_Bind(i));
                        
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_AG.set.K, res.CERAPP_Ago_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_AN.set.K, res.CERAPP_Anta_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_BD.set.K, res.CERAPP_Bind_CAS_neighbor{i,1:5});
                        end
                        
                    end
                end                
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        %Xtest=[XtestAG; XtestAN; XtestBD; XtestGHS; XtestLD50];
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    %Xtest=[XtestAG; XtestAN; XtestBD; XtestGHS; XtestLD50];
                    Xtest(:,ismember(Desc,DescNames))=[];
                    Desc(ismember(Desc,DescNames))=[];
                    DescNames=[DescNames Desc];
                    DescMat=[DescMat Xtest];
                end
                                
                if sep==1
                    resf.CERAPP=res;
                    clear('res');
                    
                end
                % Clean memory
                clear('XinCDK_CERAPP');
                clear('Xtest');
                clear('XtestAG');
                clear('XtestAN');
                clear('XtestBD');
                clear('predAG');
                clear('predAN');
                clear('predBD')
                clear('AD');
                %end clean memory
            end
            
            %--------------------------------------------
            
            %Predict CoMPARA endpoints
            %case {'CoMPARA','ER'}
            [Lia,Locb] =ismember({'compara','ar'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting Androgen Receptor Activity (CoMPARA)';
                Desc=train.CoMPARA.DescIn;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting CoMPARA endpoints... \n\n			==============================================================  \n\n');
                end
                
                %XlabelsCDK
                XinCDK_CoMPARA=XinCDK(:,train.CoMPARA.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.CoMPARA.Padel_in)), XinCDK_CoMPARA];
                
                XtestAG=Xtest(:,train.CoMPARA.model_AG.DescAG_i);
                XtestAN=Xtest(:,train.CoMPARA.model_AN.DescAN_i);
                XtestBD=Xtest(:,train.CoMPARA.model_BD.DescBD_i);
                
                predAG = knnpred2(XtestAG,train.CoMPARA.model_AG.set.train,train.CoMPARA.model_AG.set.class,train.CoMPARA.model_AG.set.K,train.CoMPARA.model_AG.set.dist_type,train.CoMPARA.model_AG.set.param.pret_type);
                predAG.D=[];
                predAN = knnpred2(XtestAN,train.CoMPARA.model_AN.set.train,train.CoMPARA.model_AN.set.class,train.CoMPARA.model_AN.set.K,train.CoMPARA.model_AN.set.dist_type,train.CoMPARA.model_AN.set.param.pret_type);
                predAN.D=[];
                predBD = knnpred2(XtestBD,train.CoMPARA.model_BD.set.train,train.CoMPARA.model_BD.set.class,train.CoMPARA.model_BD.set.K,train.CoMPARA.model_BD.set.dist_type,train.CoMPARA.model_BD.set.param.pret_type);
                predBD.D=[];
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.CoMPARA_Ago_exp=cell(size(Xtest,1),1);
                end
                res.CoMPARA_Ago_pred(:,1)=predAG.class_pred-1;
                AD=classical_leverage(train.CoMPARA.model_AG.set.train,XtestAG,'auto');
                res.AD_CoMPARA_Ago=abs(AD.inorout-1)';
                res.AD_index_CoMPARA_Ago=1-test_pretreatment(predAG.dc(:,1),train.CoMPARA.model_AG.set.dc_param);
                res.AD_index_CoMPARA_Ago(find(res.AD_index_CoMPARA_Ago<0),1)=1./(1+predAG.dc(find(res.AD_index_CoMPARA_Ago<0),1));
                res.AD_CoMPARA_Ago(find(isnan(predAG.dc(:,1))))=0;
                res.AD_index_CoMPARA_Ago(find(isnan(predAG.dc(:,1))))=0;
                res.AD_CoMPARA_Ago(find(res.AD_index_CoMPARA_Ago>0.5))=1;
                res.Conf_index_CoMPARA_Ago=zeros(size(XtestAG,1),1);
                if exp
                    res.CoMPARA_Anta_exp=cell(size(Xtest,1),1);
                end
                res.CoMPARA_Anta_pred(:,1)=predAN.class_pred-1;
                AD=classical_leverage(train.CoMPARA.model_AN.set.train,XtestAN,'auto');
                res.AD_CoMPARA_Anta=abs(AD.inorout-1)';
                res.AD_index_CoMPARA_Anta=1-test_pretreatment(predAN.dc(:,1),train.CoMPARA.model_AN.set.dc_param);
                res.AD_index_CoMPARA_Anta(find(res.AD_index_CoMPARA_Anta<0),1)=1./(1+predAN.dc(find(res.AD_index_CoMPARA_Anta<0),1));
                res.AD_CoMPARA_Anta(find(isnan(predAN.dc(:,1))))=0;
                res.AD_index_CoMPARA_Anta(find(isnan(predAN.dc(:,1))))=0;
                res.AD_CoMPARA_Anta(find(res.AD_index_CoMPARA_Anta>0.5))=1;
                res.Conf_index_CoMPARA_Anta=zeros(size(XtestAN,1),1);
                if exp
                    res.CoMPARA_Bind_exp=cell(size(Xtest,1),1);
                end
                res.CoMPARA_Bind_pred(:,1)=predBD.class_pred-1;
                AD=classical_leverage(train.CoMPARA.model_BD.set.train,XtestBD,'auto');
                res.AD_CoMPARA_Bind=abs(AD.inorout-1)';
                res.AD_index_CoMPARA_Bind=1-test_pretreatment(predBD.dc(:,1),train.CoMPARA.model_BD.set.dc_param);
                res.AD_index_CoMPARA_Bind(find(res.AD_index_CoMPARA_Bind<0),1)=1./(1+predBD.dc(find(res.AD_index_CoMPARA_Bind<0),1));
                res.AD_CoMPARA_Bind(find(isnan(predBD.dc(:,1))))=0;
                res.AD_index_CoMPARA_Bind(find(isnan(predBD.dc(:,1))))=0;
                res.AD_CoMPARA_Bind(find(res.AD_index_CoMPARA_Bind>0.5))=1;
                res.Conf_index_CoMPARA_Bind=zeros(size(XtestBD,1),1);
                                
                for i=1:size(Xtest(:,1))
                    Li_ag=0;
                    Li_an=0;
                    Li_bd=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li_ag,Lo_ag] = ismember(MoleculeNames(i),train.CoMPARA.model_AG.CAS);
                            [Li_an,Lo_an] = ismember(MoleculeNames(i),train.CoMPARA.model_AN.CAS);
                            [Li_bd,Lo_bd] = ismember(MoleculeNames(i),train.CoMPARA.model_BD.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li_ag,Lo_ag] = ismember(MoleculeNames{i},train.CoMPARA.model_AG.DTXSID);
                            [Li_an,Lo_an] = ismember(MoleculeNames{i},train.CoMPARA.model_AN.DTXSID);
                            [Li_bd,Lo_bd] = ismember(MoleculeNames{i},train.CoMPARA.model_BD.DTXSID);
                        end
                        if Li_ag
                            if Lo_ag>size(train.CoMPARA.model_AG.DTXSID,1)
                                Lo_ag=mod(Lo_ag,size(train.CoMPARA.model_AG.DTXSID,1));
                            end
                            res.CoMPARA_Ago_exp(i)=train.CoMPARA.model_AG.set.class_Exp(Lo_ag);
                        end
                        if Li_an
                            if Lo_an>size(train.CoMPARA.model_AN.DTXSID,1)
                                Lo_an=mod(Lo_an,size(train.CoMPARA.model_AN.DTXSID,1));
                            end
                            res.CoMPARA_Anta_exp(i,1)=train.CoMPARA.model_AN.set.class_Exp(Lo_an);
                        end
                        if Li_bd
                            if Lo_bd>size(train.CoMPARA.model_BD.DTXSID,1)
                                Lo_bd=mod(Lo_bd,size(train.CoMPARA.model_BD.DTXSID,1));
                            end
                            res.CoMPARA_Bind_exp(i,1)=train.CoMPARA.model_BD.set.class_Exp(Lo_bd);
                        end
                    end
                    res.Conf_index_CoMPARA_Ago(i,1)=train.CoMPARA.model_AG.conc_AG(predAG.neighbors(i,:),1)'*predAG.w(i,:)';
                    res.Conf_index_CoMPARA_Anta(i,1)=train.CoMPARA.model_AN.conc_AN(predAN.neighbors(i,:),1)'*predAN.w(i,:)';
                    res.Conf_index_CoMPARA_Bind(i,1)=train.CoMPARA.model_BD.conc_BD(predBD.neighbors(i,:),1)'*predBD.w(i,:)';
                    
                    if neighbors==1
                        res.CoMPARA_Ago_CAS_neighbor(i,:)=train.CoMPARA.model_AG.CAS(predAG.neighbors(i,:));
                        res.CoMPARA_Ago_InChiKey_neighbor(i,:)=train.CoMPARA.model_AG.InChiKey(predAG.neighbors(i,:));
                        res.CoMPARA_Ago_DTXSID_neighbor(i,:)=train.CoMPARA.model_AG.DTXSID(predAG.neighbors(i,:));
                        %res.CoMPARA_Ago_DSSTOXMPID_neighbor(i,:)=train.CoMPARA.model_AG.DSSTOXMPID(pred.neighbors(i,:));
                        res.CoMPARA_Ago_Exp_neighbor(i,:)=train.CoMPARA.model_AG.set.class_Exp(predAG.neighbors(i,:));
                        res.CoMPARA_Ago_pred_neighbor(i,:)=train.CoMPARA.model_AG.set.class_S(predAG.neighbors(i,:));
                        
                        res.CoMPARA_Anta_CAS_neighbor(i,:)=train.CoMPARA.model_AN.CAS(predAN.neighbors(i,:));
                        res.CoMPARA_Anta_InChiKey_neighbor(i,:)=train.CoMPARA.model_AN.InChiKey(predAN.neighbors(i,:));
                        res.CoMPARA_Anta_DTXSID_neighbor(i,:)=train.CoMPARA.model_AN.DTXSID(predAN.neighbors(i,:));
                        %res.CoMPARA_Anta_DSSTOXMPID_neighbor(i,:)=train.CoMPARA.model_AN.DSSTOXMPID(pred.neighbors(i,:));
                        res.CoMPARA_Anta_Exp_neighbor(i,:)=train.CoMPARA.model_AN.set.class_Exp(predAN.neighbors(i,:));
                        res.CoMPARA_Anta_pred_neighbor(i,:)=train.CoMPARA.model_AN.set.class_S(predAN.neighbors(i,:));
                        
                        res.CoMPARA_Bind_CAS_neighbor(i,:)=train.CoMPARA.model_BD.CAS(predBD.neighbors(i,:));
                        res.CoMPARA_Bind_InChiKey_neighbor(i,:)=train.CoMPARA.model_BD.InChiKey(predBD.neighbors(i,:));
                        res.CoMPARA_Bind_DTXSID_neighbor(i,:)=train.CoMPARA.model_BD.DTXSID(predBD.neighbors(i,:));
                        %res.CoMPARA_Bind_DSSTOXMPID_neighbor(i,:)=train.CoMPARA.model_BD.DSSTOXMPID(predBD.neighbors(i,:));
                        res.CoMPARA_Bind_Exp_neighbor(i,:)=train.CoMPARA.model_BD.set.class_Exp(predBD.neighbors(i,:));
                        res.CoMPARA_Bind_pred_neighbor(i,:)=train.CoMPARA.model_BD.set.class_S(predBD.neighbors(i,:));
                        
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'AG experimental= %s, AN experimental= %s, BD experimental= %s\n', res.CoMPARA_Ago_exp{i},res.CoMPARA_Anta_exp{i},res.CoMPARA_Bind_exp{i});
                        end
                        fprintf(output(Locb(find(Locb))),'AG predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Ago_pred(i),res.AD_CoMPARA_Ago(i),res.AD_index_CoMPARA_Ago(i),res.Conf_index_CoMPARA_Ago(i));
                        fprintf(output(Locb(find(Locb))),'AN predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Anta_pred(i),res.AD_CoMPARA_Anta(i),res.AD_index_CoMPARA_Anta(i),res.Conf_index_CoMPARA_Anta(i));
                        fprintf(output(Locb(find(Locb))),'BD category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Bind_pred(i),res.AD_CoMPARA_Bind(i),res.AD_index_CoMPARA_Bind(i),res.Conf_index_CoMPARA_Bind(i));
                        
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CoMPARA.model_AG.set.K, res.CoMPARA_Ago_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CoMPARA.model_AN.set.K, res.CoMPARA_Anta_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CoMPARA.model_BD.set.K, res.CoMPARA_Bind_CAS_neighbor{i,1:5});
                        end
                        
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'AG experimental= %s, AN experimental= %s, BD experimental= %s\n', res.CoMPARA_Ago_exp{i},res.CoMPARA_Anta_exp{i},res.CoMPARA_Bind_exp{i});
                        end
                        fprintf(output,'AG predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Ago_pred(i),res.AD_CoMPARA_Ago(i),res.AD_index_CoMPARA_Ago(i),res.Conf_index_CoMPARA_Ago(i));
                        fprintf(output,'AN predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Anta_pred(i),res.AD_CoMPARA_Anta(i),res.AD_index_CoMPARA_Anta(i),res.Conf_index_CoMPARA_Anta(i));
                        fprintf(output,'BD category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Bind_pred(i),res.AD_CoMPARA_Bind(i),res.AD_index_CoMPARA_Bind(i),res.Conf_index_CoMPARA_Bind(i));
                        
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CoMPARA.model_AG.set.K, res.CoMPARA_Ago_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CoMPARA.model_AN.set.K, res.CoMPARA_Anta_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CoMPARA.model_BD.set.K, res.CoMPARA_Bind_CAS_neighbor{i,1:5});
                        end
                        
                    end
                end
                
                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        %Xtest=[XtestAG; XtestAN; XtestBD; XtestGHS; XtestLD50];
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    %Xtest=[XtestAG; XtestAN; XtestBD; XtestGHS; XtestLD50];
                    Xtest(:,ismember(Desc,DescNames))=[];
                    Desc(ismember(Desc,DescNames))=[];
                    DescNames=[DescNames Desc];
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.CoMPARA=res;
                    clear('res');
                    
                end
                % Clean memory
                clear('XinCDK_CoMPARA');
                clear('Xtest');
                clear('XtestAG');
                clear('XtestAN');
                clear('XtestBD');
                clear('predAG');
                clear('predAN');
                clear('predBD')
                clear('AD');
                %end clean memory
            end
            
            %--------------------------------------------
            %Predict CATMoS endpoints
            %case {'CATMoS','AcuteTox'}
            [Lia,Locb] =ismember({'catmos','acutetox'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Predicting Acute Oral Tox. endpoints (CATMoS)';
                Desc=train.CATMoS.DescIn;

                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting CATMoS endpoints... \n\n			==============================================================  \n\n');
                end
                

                XinCDK_CATMoS=XinCDK(:,train.CATMoS.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.CATMoS.Padel_in)), XinCDK_CATMoS];
                
                XtestVT=Xtest(:,train.CATMoS.model_VT.DescVT_i);
                XtestNT=Xtest(:,train.CATMoS.model_NT.DescNT_i);
                XtestEPA=Xtest(:,train.CATMoS.model_EPA.DescEPA_i);
                XtestGHS=Xtest(:,train.CATMoS.model_GHS.DescGHS_i);
                XtestLD50=Xtest(:,train.CATMoS.model_LD50.DescLD50_i);
                
                predVT = knnpred2(XtestVT,train.CATMoS.model_VT.set.train,train.CATMoS.model_VT.set.class,train.CATMoS.model_VT.set.K,train.CATMoS.model_VT.set.dist_type,train.CATMoS.model_VT.set.param.pret_type);
                predVT.D=[];
                predNT = knnpred2(XtestNT,train.CATMoS.model_NT.set.train,train.CATMoS.model_NT.set.class,train.CATMoS.model_NT.set.K,train.CATMoS.model_NT.set.dist_type,train.CATMoS.model_NT.set.param.pret_type);
                predNT.D=[];
                predEPA = knnpred2(XtestEPA,train.CATMoS.model_EPA.set.train,train.CATMoS.model_EPA.set.class,train.CATMoS.model_EPA.set.K,train.CATMoS.model_EPA.set.dist_type,train.CATMoS.model_EPA.set.param.pret_type);
                predEPA.D=[];
                predGHS = knnpred2(XtestGHS,train.CATMoS.model_GHS.set.train,train.CATMoS.model_GHS.set.class,train.CATMoS.model_GHS.set.K,train.CATMoS.model_GHS.set.dist_type,train.CATMoS.model_GHS.set.param.pret_type);
                predGHS.D=[];
                predLD50 = nnrpred2(XtestLD50,train.CATMoS.model_LD50.set.train,train.CATMoS.model_LD50.set.y,train.CATMoS.model_LD50.set.K,train.CATMoS.model_LD50.set.dist_type,train.CATMoS.model_LD50.set.param.pret_type);
                predLD50.D=[];
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.CATMoS_VT_exp=NaN(size(Xtest,1),1);
                end
                res.CATMoS_VT_pred(:,1)=predVT.class_pred-1;
                AD=classical_leverage(train.CATMoS.model_VT.set.train,XtestVT,'auto');
                res.AD_VT=abs(AD.inorout-1)';
                res.AD_index_VT=1-test_pretreatment(predVT.dc(:,1),train.CATMoS.model_VT.set.dc_param);
                res.AD_index_VT(find(res.AD_index_VT<0),1)=1./(1+predVT.dc(find(res.AD_index_VT<0),1));
                res.AD_VT(find(isnan(predVT.dc(:,1))))=0;
                res.AD_index_VT(find(isnan(predVT.dc(:,1))))=0;
                res.AD_VT(find(res.AD_index_VT>0.5))=1;
                res.Conf_index_VT=zeros(size(XtestVT,1),1);
                if exp
                    res.CATMoS_NT_exp=NaN(size(Xtest,1),1);
                end
                res.CATMoS_NT_pred(:,1)=predNT.class_pred-1;
                AD=classical_leverage(train.CATMoS.model_NT.set.train,XtestNT,'auto');
                res.AD_NT=abs(AD.inorout-1)';
                res.AD_index_NT=1-test_pretreatment(predNT.dc(:,1),train.CATMoS.model_NT.set.dc_param);
                res.AD_index_NT(find(res.AD_index_NT<0),1)=1./(1+predNT.dc(find(res.AD_index_NT<0),1));
                res.AD_NT(find(isnan(predNT.dc(:,1))))=0;
                res.AD_index_NT(find(isnan(predNT.dc(:,1))))=0;
                res.AD_NT(find(res.AD_index_NT>0.5))=1;
                res.Conf_index_NT=zeros(size(XtestNT,1),1);
                if exp
                    res.CATMoS_EPA_exp=NaN(size(Xtest,1),1);
                end
                res.CATMoS_EPA_pred(:,1)=predEPA.class_pred;
                AD=classical_leverage(train.CATMoS.model_EPA.set.train,XtestEPA,'auto');
                res.AD_EPA=abs(AD.inorout-1)';
                res.AD_index_EPA=1-test_pretreatment(predEPA.dc(:,1),train.CATMoS.model_EPA.set.dc_param);
                res.AD_index_EPA(find(res.AD_index_EPA<0),1)=1./(1+predEPA.dc(find(res.AD_index_EPA<0),1));
                res.AD_EPA(find(isnan(predEPA.dc(:,1))))=0;
                res.AD_index_EPA(find(isnan(predEPA.dc(:,1))))=0;
                res.AD_EPA(find(res.AD_index_EPA>0.5))=1;
                res.Conf_index_EPA=zeros(size(XtestEPA,1),1);
                if exp
                    res.CATMoS_GHS_exp=NaN(size(Xtest,1),1);
                end
                res.CATMoS_GHS_pred(:,1)=predGHS.class_pred;
                AD=classical_leverage(train.CATMoS.model_GHS.set.train,XtestGHS,'auto');
                res.AD_GHS=abs(AD.inorout-1)';
                res.AD_index_GHS=1-test_pretreatment(predGHS.dc(:,1),train.CATMoS.model_GHS.set.dc_param);
                res.AD_index_GHS(find(res.AD_index_GHS<0),1)=1./(1+predGHS.dc(find(res.AD_index_GHS<0),1));
                res.AD_GHS(find(isnan(predGHS.dc(:,1))))=0;
                res.AD_index_GHS(find(isnan(predGHS.dc(:,1))))=0;
                res.AD_GHS(find(res.AD_index_GHS>0.5))=1;
                res.Conf_index_GHS=zeros(size(XtestGHS,1),1);
                if exp
                    res.CATMoS_LD50_exp=NaN(size(Xtest,1),1);
                end
                res.CATMoS_LD50_pred(:,1)=predLD50.y_pred_weighted;
                AD=classical_leverage(train.CATMoS.model_LD50.set.train,XtestLD50,'auto');
                res.AD_LD50=abs(AD.inorout-1)';
                res.AD_index_LD50=1-test_pretreatment(predLD50.dc(:,1),train.CATMoS.model_LD50.set.dc_param);
                res.AD_index_LD50(find(res.AD_index_LD50<0),1)=1./(1+predLD50.dc(find(res.AD_index_LD50<0),1));
                res.AD_LD50(find(isnan(predLD50.dc(:,1))))=0;
                res.AD_index_LD50(find(isnan(predLD50.dc(:,1))))=0;
                res.AD_LD50(find(res.AD_index_LD50>0.5))=1;
                res.Conf_index_LD50=zeros(size(XtestLD50,1),1);
                
                
                for i=1:size(Xtest(:,1))
                    Li_vt=0;
                    Li_nt=0;
                    Li_epa=0;
                    Li_ghs=0;
                    Li_ld50=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li_vt,Lo_vt] = ismember(MoleculeNames(i),train.CATMoS.model_VT.CAS);
                            [Li_nt,Lo_nt] = ismember(MoleculeNames(i),train.CATMoS.model_NT.CAS);
                            [Li_epa,Lo_epa] = ismember(MoleculeNames(i),train.CATMoS.model_EPA.CAS);
                            [Li_ghs,Lo_ghs] = ismember(MoleculeNames(i),train.CATMoS.model_GHS.CAS);
                            [Li_ld50,Lo_ld50] = ismember(MoleculeNames(i),train.CATMoS.model_LD50.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li_vt,Lo_vt] = ismember(MoleculeNames{i},train.CATMoS.model_VT.DTXSID);
                            [Li_nt,Lo_nt] = ismember(MoleculeNames{i},train.CATMoS.model_NT.DTXSID);
                            [Li_epa,Lo_epa] = ismember(MoleculeNames{i},train.CATMoS.model_EPA.DTXSID);
                            [Li_ghs,Lo_ghs] = ismember(MoleculeNames{i},train.CATMoS.model_GHS.DTXSID);
                            [Li_ld50,Lo_ld50] = ismember(MoleculeNames{i},train.CATMoS.model_LD50.DTXSID);
                        end
                        if Li_vt
                            res.CATMoS_VT_exp(i,1)=train.CATMoS.model_VT.set.class_Exp(Lo_vt);
                        end
                        if Li_nt
                            res.CATMoS_NT_exp(i)=train.CATMoS.model_NT.set.class_Exp(Lo_nt);
                        end
                        if Li_epa
                            res.CATMoS_EPA_exp(i)=train.CATMoS.model_EPA.set.class_Exp(Lo_epa);
                        end
                        if Li_ghs
                            res.CATMoS_GHS_exp(i)=train.CATMoS.model_GHS.set.class_Exp(Lo_ghs);
                        end
                        if Li_ld50
                            res.CATMoS_LD50_exp(i,1)=train.CATMoS.model_LD50.set.y_Exp_n(Lo_ld50);
                        end
                    end
                    %res.AD_index_VT(i,1)=1./(1+predVT.dc(i,1)*predVT.w(i,~isnan(predVT.dc(i,1)))');
                    
                    res.Conf_index_VT(i,1)=train.CATMoS.model_VT.conc_VT(predVT.neighbors(i,:),1)'*predVT.w(i,:)';
                    res.Conf_index_NT(i,1)=train.CATMoS.model_NT.conc_NT(predNT.neighbors(i,:),1)'*predNT.w(i,:)';
                    res.Conf_index_EPA(i,1)=train.CATMoS.model_EPA.conc_EPA(predEPA.neighbors(i,:),1)'*predEPA.w(i,:)';
                    res.Conf_index_GHS(i,1)=train.CATMoS.model_GHS.conc_GHS(predGHS.neighbors(i,:),1)'*predGHS.w(i,:)';
                    res.Conf_index_LD50(i,1)=train.CATMoS.model_LD50.conc_LD50(predLD50.neighbors(i,:),1)'*predLD50.w(i,:)';
                    
                    if neighbors==1
                        res.VT_CATMoS_ID_neighbor(i,:)=train.CATMoS.model_VT.ChemID(predVT.neighbors(i,:));
                        res.VT_CAS_neighbor(i,:)=train.CATMoS.model_VT.CAS(predVT.neighbors(i,:));
                        res.VT_InChiKey_neighbor(i,:)=train.CATMoS.model_VT.InChiKey(predVT.neighbors(i,:));
                        res.VT_DTXSID_neighbor(i,:)=train.CATMoS.model_VT.DTXSID(predVT.neighbors(i,:));
                        %res.VT_DSSTOXMPID_neighbor(i,:)=train.CATMoS.model_VT.DSSTOXMPID(pred.neighbors(i,:));
                        res.VT_Exp_neighbor(i,:)=train.CATMoS.model_VT.set.class_Exp(predVT.neighbors(i,:));
                        res.VT_pred_neighbor(i,:)=train.CATMoS.model_VT.set.class(predVT.neighbors(i,:))-1;
                        
                        res.NT_CATMoS_ID_neighbor(i,:)=train.CATMoS.model_NT.ChemID(predNT.neighbors(i,:));
                        res.NT_CAS_neighbor(i,:)=train.CATMoS.model_NT.CAS(predNT.neighbors(i,:));
                        res.NT_InChiKey_neighbor(i,:)=train.CATMoS.model_NT.InChiKey(predNT.neighbors(i,:));
                        res.NT_DTXSID_neighbor(i,:)=train.CATMoS.model_NT.DTXSID(predNT.neighbors(i,:));
                        %res.NT_DSSTOXMPID_neighbor(i,:)=train.CATMoS.model_NT.DSSTOXMPID(pred.neighbors(i,:));
                        res.NT_Exp_neighbor(i,:)=train.CATMoS.model_NT.set.class_Exp(predNT.neighbors(i,:));
                        res.NT_pred_neighbor(i,:)=train.CATMoS.model_NT.set.class(predNT.neighbors(i,:))-1;
                        
                        res.EPA_CATMoS_ID_neighbor(i,:)=train.CATMoS.model_EPA.ChemID(predEPA.neighbors(i,:));
                        res.EPA_CAS_neighbor(i,:)=train.CATMoS.model_EPA.CAS(predEPA.neighbors(i,:));
                        res.EPA_InChiKey_neighbor(i,:)=train.CATMoS.model_EPA.InChiKey(predEPA.neighbors(i,:));
                        res.EPA_DTXSID_neighbor(i,:)=train.CATMoS.model_EPA.DTXSID(predEPA.neighbors(i,:));
                        %res.EPA_DSSTOXMPID_neighbor(i,:)=train.CATMoS.model_EPA.DSSTOXMPID(predEPA.neighbors(i,:));
                        res.EPA_Exp_neighbor(i,:)=train.CATMoS.model_EPA.set.class_Exp(predEPA.neighbors(i,:));
                        res.EPA_pred_neighbor(i,:)=train.CATMoS.model_EPA.set.class(predEPA.neighbors(i,:));
                        
                        res.GHS_CATMoS_ID_neighbor(i,:)=train.CATMoS.model_GHS.ChemID(predGHS.neighbors(i,:));
                        res.GHS_CAS_neighbor(i,:)=train.CATMoS.model_GHS.CAS(predGHS.neighbors(i,:));
                        res.GHS_InChiKey_neighbor(i,:)=train.CATMoS.model_GHS.InChiKey(predGHS.neighbors(i,:));
                        res.GHS_DTXSID_neighbor(i,:)=train.CATMoS.model_GHS.DTXSID(predGHS.neighbors(i,:));
                        %res.GHS_DSSTOXMPID_neighbor(i,:)=train.CATMoS.model_GHS.DSSTOXMPID(pred.neighbors(i,:));
                        res.GHS_Exp_neighbor(i,:)=train.CATMoS.model_GHS.set.class_Exp(predGHS.neighbors(i,:));
                        res.GHS_pred_neighbor(i,:)=train.CATMoS.model_GHS.set.class(predGHS.neighbors(i,:));
                        
                        res.LD50_CATMoS_ID_neighbor(i,:)=train.CATMoS.model_LD50.ChemID(predLD50.neighbors(i,:));
                        res.LD50_CAS_neighbor(i,:)=train.CATMoS.model_LD50.CAS(predLD50.neighbors(i,:));
                        res.LD50_InChiKey_neighbor(i,:)=train.CATMoS.model_LD50.InChiKey(predLD50.neighbors(i,:));
                        res.LD50_DTXSID_neighbor(i,:)=train.CATMoS.model_LD50.DTXSID(predLD50.neighbors(i,:));
                        %res.LD50_DSSTOXMPID_neighbor(i,:)=train.CATMoS.model_LD50.DSSTOXMPID(pred.neighbors(i,:));
                        res.LD50_Exp_neighbor(i,:)=train.CATMoS.model_LD50.set.y_Exp(predLD50.neighbors(i,:));
                        res.LD50_pred_neighbor(i,:)=train.CATMoS.model_LD50.set.y(predLD50.neighbors(i,:));
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'VT experimental= %i, NT experimental= %i, EPA category experimental= %i, GHS category experimental= %i, LD50 experimental= %.3f\n', res.CATMoS_VT_exp(i),res.CATMoS_NT_exp(i),res.CATMoS_EPA_exp(i),res.CATMoS_GHS_exp(i),res.CATMoS_LD50_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'VT predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_VT_pred(i),res.AD_VT(i),res.AD_index_VT(i),res.Conf_index_VT(i));
                        fprintf(output(Locb(find(Locb))),'NT predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_NT_pred(i),res.AD_NT(i),res.AD_index_NT(i),res.Conf_index_NT(i));
                        fprintf(output(Locb(find(Locb))),'EPA category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_EPA_pred(i),res.AD_EPA(i),res.AD_index_EPA(i),res.Conf_index_EPA(i));
                        fprintf(output(Locb(find(Locb))),'GHS category predicted= %i, AD: %i,AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_GHS_pred(i),res.AD_GHS(i),res.AD_index_GHS(i),res.Conf_index_GHS(i));
                        fprintf(output(Locb(find(Locb))),'LD50 predicted= %.3f, AD: %i,AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_LD50_pred(i),res.AD_LD50(i),res.AD_index_LD50(i),res.Conf_index_LD50(i));
                        
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_VT.set.K, res.VT_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_NT.set.K, res.NT_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_EPA.set.K, res.EPA_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_GHS.set.K, res.GHS_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_LD50.set.K, res.LD50_CAS_neighbor{i,1:5});
                        end
                        
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'VT experimental= %i, NT experimental= %i, EPA category experimental= %i, GHS category experimental= %i, LD50 experimental= %.3f\n', res.CATMoS_VT_exp(i),res.CATMoS_NT_exp(i),res.CATMoS_EPA_exp(i),res.CATMoS_GHS_exp(i),res.CATMoS_LD50_exp(i));
                        end
                        fprintf(output,'VT predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_VT_pred(i),res.AD_VT(i),res.AD_index_VT(i),res.Conf_index_VT(i));
                        fprintf(output,'NT predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_NT_pred(i),res.AD_NT(i),res.AD_index_NT(i),res.Conf_index_NT(i));
                        fprintf(output,'EPA category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_EPA_pred(i),res.AD_EPA(i),res.AD_index_EPA(i),res.Conf_index_EPA(i));
                        fprintf(output,'GHS category predicted= %i, AD: %i,AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_GHS_pred(i),res.AD_GHS(i),res.AD_index_GHS(i),res.Conf_index_GHS(i));
                        fprintf(output,'LD50 predicted= %.3f, AD: %i,AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_LD50_pred(i),res.AD_LD50(i),res.AD_index_LD50(i),res.Conf_index_LD50(i));
                        
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_VT.set.K, res.VT_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_NT.set.K, res.NT_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_EPA.set.K, res.EPA_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_GHS.set.K, res.GHS_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMoS.model_LD50.set.K, res.LD50_CAS_neighbor{i,1:5});
                        end
                        
                    end
                end
                                
                if sep==1 && strcmpi(ext,'.csv')
                    T=struct2table(res);
                    if printtDesc==1
                        %Xtest=[XtestVT; XtestNT; XtestEPA; XtestGHS; XtestLD50];
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    %Xtest=[XtestVT; XtestNT; XtestEPA; XtestGHS; XtestLD50];
                    Xtest(:,ismember(Desc,DescNames))=[];
                    Desc(ismember(Desc,DescNames))=[];
                    DescNames=[DescNames Desc];
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.CATMoS=res;
                    clear('res');
                    
                end
                % Clean memory
                clear('XinCDK_CATMoS');
                clear('Xtest');
                clear('XtestVT');
                clear('XtestNT');
                clear('XtestEPA');
                clear('XtestGHS');
                clear('XtestLD50');
                clear('predVT');
                clear('predNT');
                clear('predEPA');
                clear('predGHS');
                clear('predLD50');
                clear('AD');
                %end clean memory
            end
      %--------------------------------------------------------------------------
      %========== End Of Calculation ==========
            
            if sep==0 && strcmpi(ext,'.csv')
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                   msgbox('Canceled by user', 'Error','error');
                   return;
                end
                d.Message='Writing output file';
                res=struct2table(res);
                if printtDesc==1
                    
                    DescMat=array2table(DescMat,'VariableNames',DescNames);
                    res=[res DescMat];
                end
                writetable(res,FileOut,'Delimiter',',');
                fclose('all');
            end
            app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'Predicted structures: ' num2str(length(MoleculeNames)) ' (' num2str(round(toc(timerCalc))) ' sec)']; 
            if sep==1
                res=resf;
            end
            tElapsed = toc(timerAll);
            app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'Total processing time: ' num2str(round(tElapsed,2)) ' seconds.'];
            myicon = imread('Icon_ba.png');
msgbox(['Calculations done in: ', num2str(round(tElapsed,2)),' seconds.'],'Success!','custom',myicon);
        end

        % Value changed function: PhyschempropertiesCheckBox
        function PhyschempropertiesCheckBoxValueChanged(app, event)
            value = app.PhyschempropertiesCheckBox.Value;
            if value
                app.LogPCheckBox.Value=1;
                app.MPCheckBox.Value=1;
                app.BPCheckBox.Value=1;
                app.VPCheckBox.Value=1;
                app.WSCheckBox.Value=1;
                app.HLCheckBox.Value=1;
                app.KOACheckBox.Value=1;
                app.RTCheckBox.Value=1;
                app.pKaCheckBox.Value=1;
                app.LogDCheckBox.Value=1;
            else
                app.LogPCheckBox.Value=0;
                app.MPCheckBox.Value=0;
                app.BPCheckBox.Value=0;
                app.VPCheckBox.Value=0;
                app.WSCheckBox.Value=0;
                app.HLCheckBox.Value=0;
                app.KOACheckBox.Value=0;
                app.RTCheckBox.Value=0;
                app.pKaCheckBox.Value=0;
                app.LogDCheckBox.Value=0;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
myicon = imread('Icon_ba.png');
msgbox(sprintf(['OPERA ',app.SubVersion,' ',app.Date,'\nhttps://github.com/NIEHS/OPERA\nSupport: kamel.mansouri@nih.gov']),'About','custom',myicon);
        end

        % Value changed function: EnvironmentalfateCheckBox
        function EnvironmentalfateCheckBoxValueChanged(app, event)
            value = app.EnvironmentalfateCheckBox.Value;
            if value 
                app.LogBCFCheckBox.Value=1;
                app.AOHCheckBox.Value=1;
                app.BiodegCheckBox.Value=1;
                app.RBiodegCheckBox.Value=1;
                app.KMCheckBox.Value=1;
                app.KOCCheckBox.Value=1;
            else
                app.LogBCFCheckBox.Value=0;
                app.AOHCheckBox.Value=0;
                app.BiodegCheckBox.Value=0;
                app.RBiodegCheckBox.Value=0;
                app.KMCheckBox.Value=0;
                app.KOCCheckBox.Value=0;
            end
        end

        % Value changed function: ToxicityendpointsCheckBox
        function ToxicityendpointsCheckBoxValueChanged(app, event)
            value = app.ToxicityendpointsCheckBox.Value;
            if value
                app.ERCERAPPCheckBox.Value=1;
                app.ARCoMPARACheckBox.Value=1;
                app.AcuteToxCATMoSCheckBox.Value=1;
            else
                app.ERCERAPPCheckBox.Value=0;
                app.ARCoMPARACheckBox.Value=0;
                app.AcuteToxCATMoSCheckBox.Value=0;
            end
        end

        % Button pushed function: InfoModels
        function InfoModelsPushed(app, event)
            %
            %f = figure('menu','none','toolbar','none','Name','Models','NumberTitle','off');
            fid = fopen('Endpoints.txt');
            %ph = uipanel(f,'Units','normalized'); %'title','Display window');
            %lbh = uicontrol(ph,'style','listbox','Units','normalized','position',[0 0 1 1],'FontSize',9);
            indic = 1;
            while 1
                tline = fgetl(fid);
                if ~ischar(tline)
                    break
                end
                strings{indic}=tline;
                indic = indic + 1;
            end
            fclose(fid);
            %set(lbh,'string',strings);
            %set(lbh,'Value',1);
            %set(lbh,'Selected','on');
            msgbox(strings,['Models (V',app.SubVersion,')'],'modal');
        end

        % Button pushed function: InfoOutput
        function InfoOutputButtonPushed(app, event)
            %f = figure('menu','none','toolbar','none','Name','Models','NumberTitle','off');
            fid = fopen('output_options.txt');
            %ph = uipanel(f,'Units','normalized'); %'title','Display window');
            %lbh = uicontrol(ph,'style','listbox','Units','normalized','position',[0 0 1 1],'FontSize',9);
            indic = 1;
            while 1
                tline = fgetl(fid);
                if ~ischar(tline)
                    break
                end
                strings{indic}=tline;
                indic = indic + 1;
            end
            fclose(fid);
            %set(lbh,'string',strings);
            %set(lbh,'Value',1);
            %set(lbh,'Selected','on');
            msgbox(strings,'Output options','modal');
        end

        % Button pushed function: InfoResutls
        function InfoResutlsButtonPushed(app, event)
            msgbox(sprintf('Different steps and time of excecution:\n - Loading structures from SMI, SDF or MOL input file\n - Descriptor calcualtion using PaDEL & CDK)\n - Running the models and writing resutls'),'Results summary');
        end

        % Value changed function: StandardizeButton
        function StandardizeButtonValueChanged(app, event)
            msgbox(sprintf('Generate QSAR-ready structures.'), 'Coming soon!','warn');
        end

        % Value changed function: ADMEpropertiesCheckBox
        function ADMEpropertiesCheckBoxValueChanged(app, event)
            value = app.ADMEpropertiesCheckBox.Value;
            if value
                app.FUBCheckBox.Value=1;
                app.ClintCheckBox.Value=1;
            else
                app.FUBCheckBox.Value=0;
                app.ClintCheckBox.Value=0;
            end
        end

        % Button pushed function: InfoModels_2
        function InfoModels_2ButtonPushed(app, event)
            %f = figure('menu','none','toolbar','none','Name','Models','NumberTitle','off');
            fid = fopen('input_file.txt');
            %ph = uipanel(f,'Units','normalized'); %'title','Display window');
            %lbh = uicontrol(ph,'style','listbox','Units','normalized','position',[0 0 1 1],'FontSize',9);
            indic = 1;
            while 1
                tline = fgetl(fid);
                if ~ischar(tline)
                    break
                end
                strings{indic}=tline;
                indic = indic + 1;
            end
            fclose(fid);
            %set(lbh,'string',strings);
            %set(lbh,'Value',1);
            %set(lbh,'Selected','on');
            msgbox(strings,'Input file','modal');
        end

        % Button pushed function: InfoModels_3
        function InfoModels_3ButtonPushed(app, event)
            %f = figure('menu','none','toolbar','none','Name','Models','NumberTitle','off');
            fid = fopen('output_file.txt');
            %ph = uipanel(f,'Units','normalized'); %'title','Display window');
            %lbh = uicontrol(ph,'style','listbox','Units','normalized','position',[0 0 1 1],'FontSize',9);
            indic = 1;
            while 1
                tline = fgetl(fid);
                if ~ischar(tline)
                    break
                end
                strings{indic}=tline;
                indic = indic + 1;
            end
            fclose(fid);
            %set(lbh,'string',strings);
            %set(lbh,'Value',1);
            %set(lbh,'Selected','on');
            msgbox(strings,'Output file','modal');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OPERA = uifigure;
            app.OPERA.Name = 'OPERA 2.3';
            app.BrowseButton = uibutton(app.OPERA, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.InputLabel = uilabel(app.OPERA);
            app.InputLabel.Text = 'Input';
            app.InputTextArea = uitextarea(app.OPERA);
            app.OutputTextAreaLabel = uilabel(app.OPERA);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.OPERA);
            app.CalculateButton = uibutton(app.OPERA, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ModelsPanel = uipanel(app.OPERA);
            app.ModelsPanel.Title = 'Models';
            app.LogPCheckBox = uicheckbox(app.ModelsPanel);
            app.LogPCheckBox.Text = 'LogP';
            app.MPCheckBox = uicheckbox(app.ModelsPanel);
            app.MPCheckBox.Text = 'MP';
            app.PhyschempropertiesCheckBox = uicheckbox(app.ModelsPanel);
            app.PhyschempropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhyschempropertiesCheckBoxValueChanged, true);
            app.PhyschempropertiesCheckBox.Text = 'Physchem properties';
            app.BPCheckBox = uicheckbox(app.ModelsPanel);
            app.BPCheckBox.Text = 'BP';
            app.VPCheckBox = uicheckbox(app.ModelsPanel);
            app.VPCheckBox.Text = 'VP';
            app.WSCheckBox = uicheckbox(app.ModelsPanel);
            app.WSCheckBox.Text = 'WS';
            app.HLCheckBox = uicheckbox(app.ModelsPanel);
            app.HLCheckBox.Text = 'HL';
            app.KOACheckBox = uicheckbox(app.ModelsPanel);
            app.KOACheckBox.Text = 'KOA';
            app.RTCheckBox = uicheckbox(app.ModelsPanel);
            app.RTCheckBox.Text = 'RT';
            app.pKaCheckBox = uicheckbox(app.ModelsPanel);
            app.pKaCheckBox.Text = 'pKa';
            app.LogDCheckBox = uicheckbox(app.ModelsPanel);
            app.LogDCheckBox.Text = 'LogD';
            app.EnvironmentalfateCheckBox = uicheckbox(app.ModelsPanel);
            app.EnvironmentalfateCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnvironmentalfateCheckBoxValueChanged, true);
            app.EnvironmentalfateCheckBox.Text = 'Environmental fate';
            app.ToxicityendpointsCheckBox = uicheckbox(app.ModelsPanel);
            app.ToxicityendpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToxicityendpointsCheckBoxValueChanged, true);
            app.ToxicityendpointsCheckBox.Text = 'Toxicity endpoints';
            app.LogBCFCheckBox = uicheckbox(app.ModelsPanel);
            app.LogBCFCheckBox.Text = 'LogBCF';
            app.AOHCheckBox = uicheckbox(app.ModelsPanel);
            app.AOHCheckBox.Text = 'AOH';
            app.BiodegCheckBox = uicheckbox(app.ModelsPanel);
            app.BiodegCheckBox.Text = 'Biodeg';
            app.RBiodegCheckBox = uicheckbox(app.ModelsPanel);
            app.RBiodegCheckBox.Text = 'R-Biodeg';
            app.KMCheckBox = uicheckbox(app.ModelsPanel);
            app.KMCheckBox.Text = 'KM';
            app.KOCCheckBox = uicheckbox(app.ModelsPanel);
            app.KOCCheckBox.Text = 'KOC';
            app.ERCERAPPCheckBox = uicheckbox(app.ModelsPanel);
            app.ERCERAPPCheckBox.Text = 'ER (CERAPP)';
            app.ARCoMPARACheckBox = uicheckbox(app.ModelsPanel);
            app.ARCoMPARACheckBox.Text = 'AR (CoMPARA)';
            app.AcuteToxCATMoSCheckBox = uicheckbox(app.ModelsPanel);
            app.AcuteToxCATMoSCheckBox.Text = 'AcuteTox (CATMoS)';
            app.ADMEpropertiesCheckBox = uicheckbox(app.ModelsPanel);
            app.ADMEpropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ADMEpropertiesCheckBoxValueChanged, true);
            app.ADMEpropertiesCheckBox.Text = 'ADME properties';
            app.FUBCheckBox = uicheckbox(app.ModelsPanel);
            app.FUBCheckBox.Text = 'FUB';
            app.ClintCheckBox = uicheckbox(app.ModelsPanel);
            app.ClintCheckBox.Text = 'Clint';
            app.ResultssummaryTextAreaLabel = uilabel(app.OPERA);
            app.ResultssummaryTextAreaLabel.Text = 'Results summary';
            app.ResultssummaryTextArea = uitextarea(app.OPERA);
            app.ResultssummaryTextArea.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.ResultssummaryTextArea.Editable = 'off';
            app.OutputoptionsPanel = uipanel(app.OPERA);
            app.OutputoptionsPanel.Title = 'Output options';
            app.SeparatefilesCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.SeparatefilesCheckBox.Text = 'Separate files';
            app.NearestneighborsCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.NearestneighborsCheckBox.Text = 'Nearest neighbors';
            app.KeepfulldescriptorsfilesCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.KeepfulldescriptorsfilesCheckBox.Text = 'Keep full descriptors files';
            app.IncludedescriptorvaluesCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.IncludedescriptorvaluesCheckBox.Text = 'Include descriptor values';
            app.ExperimentalvaluesCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.ExperimentalvaluesCheckBox.Text = 'Experimental values';
            app.Button_2 = uibutton(app.OPERA, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Logo2.png';
            app.Button_2.Text = '';
            app.InfoModels = uibutton(app.OPERA, 'push');
            app.InfoModels.ButtonPushedFcn = createCallbackFcn(app, @InfoModelsPushed, true);
            app.InfoModels.Icon = '996108.png';
            app.InfoModels.Text = '';
            app.InfoOutput = uibutton(app.OPERA, 'push');
            app.InfoOutput.ButtonPushedFcn = createCallbackFcn(app, @InfoOutputButtonPushed, true);
            app.InfoOutput.Icon = '996108.png';
            app.InfoOutput.Text = '';
            app.BrowseButton_2 = uibutton(app.OPERA, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.InfoResutls = uibutton(app.OPERA, 'push');
            app.InfoResutls.ButtonPushedFcn = createCallbackFcn(app, @InfoResutlsButtonPushed, true);
            app.InfoResutls.Icon = '996108.png';
            app.InfoResutls.Text = '';
            app.StandardizeButton = uibutton(app.OPERA, 'state');
            app.StandardizeButton.ValueChangedFcn = createCallbackFcn(app, @StandardizeButtonValueChanged, true);
            app.StandardizeButton.Text = 'Standardize';
            app.InfoModels_2 = uibutton(app.OPERA, 'push');
            app.InfoModels_2.ButtonPushedFcn = createCallbackFcn(app, @InfoModels_2ButtonPushed, true);
            app.InfoModels_2.Icon = '996108.png';
            app.InfoModels_2.Text = '';
            app.InfoModels_3 = uibutton(app.OPERA, 'push');
            app.InfoModels_3.ButtonPushedFcn = createCallbackFcn(app, @InfoModels_3ButtonPushed, true);
            app.InfoModels_3.Icon = '996108.png';
            app.InfoModels_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OPERA_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OPERA)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OPERA)
        end
    end
end